classdef EyeTrackEditTasks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EyeTrackEditTasksFigure        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        tabViewTasks                   matlab.ui.container.Tab
        TreeTasks                      matlab.ui.container.Tree
        axThumbnail                    matlab.ui.control.UIAxes
        lblTaskTree                    matlab.ui.control.Label
        tblAOIs                        matlab.ui.control.Table
        SelectAOIstoDisplayLabel       matlab.ui.control.Label
        ddDrawAOIs                     matlab.ui.control.DropDown
        AddNewTaskandImagesTab         matlab.ui.container.Tab
        CreateNewTaskPanel             matlab.ui.container.Panel
        TaskTypeDropDownLabel          matlab.ui.control.Label
        ddTaskType                     matlab.ui.control.DropDown
        TaskNameEditFieldLabel         matlab.ui.control.Label
        txtTaskName                    matlab.ui.control.EditField
        ChooseMonitorDropDownLabel     matlab.ui.control.Label
        ddMonitor                      matlab.ui.control.DropDown
        tblMonitor                     matlab.ui.control.Table
        AddImagestoTaskPanel           matlab.ui.container.Panel
        ChooseLoadOptionDropDownLabel  matlab.ui.control.Label
        ddChooseImageLoad              matlab.ui.control.DropDown
        PathLabel                      matlab.ui.control.Label
        txtImagePath                   matlab.ui.control.EditField
        btnGetImagePath                matlab.ui.control.Button
        btnSaveNewTask                 matlab.ui.control.Button
        tblNewImages                   matlab.ui.control.Table
        ddTaskForNewImages             matlab.ui.control.DropDown
        btnGrpAddImages                matlab.ui.container.ButtonGroup
        btnAddImagesToExisting         matlab.ui.control.RadioButton
        btnAddImagesToNew              matlab.ui.control.RadioButton
        lblSelectTaskImages            matlab.ui.control.Label
        btnClearImageTable             matlab.ui.control.Button
        AddAOIsTab                     matlab.ui.container.Tab
        TaskImageandAOIDetailsPanel    matlab.ui.container.Panel
        ChooseTaskLabel                matlab.ui.control.Label
        ddChooseTask                   matlab.ui.control.DropDown
        ChooseImageLabel               matlab.ui.control.Label
        ddChooseImage                  matlab.ui.control.DropDown
        AOIEntryMethodLabel            matlab.ui.control.Label
        ddChooseAOIEntry               matlab.ui.control.DropDown
        AOIShapeLabel                  matlab.ui.control.Label
        ddChooseAOIShape               matlab.ui.control.DropDown
        DrawAOIInteractivelyPanel      matlab.ui.container.Panel
        btnLoadImageForAOIUI           matlab.ui.control.Button
        tblNewAOIs                     matlab.ui.control.Table
        btnSaveNewAOIs                 matlab.ui.control.Button
        btnClearAOITable               matlab.ui.control.Button
        btnDisplayDrawnAois            matlab.ui.control.Button
        ManualAOIPanel                 matlab.ui.container.Panel
        tblManualNewAOIs               matlab.ui.control.Table
        btnSaveManualNewAOIs           matlab.ui.control.Button
        btnClearManualAOITable         matlab.ui.control.Button
        btnDisplayManualAois           matlab.ui.control.Button
        CopyAOIsTab                    matlab.ui.container.Tab
        AddNewMonitorTab               matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        DrawModeOFFLabel               matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        getLibButton                   matlab.ui.control.Button
        getMonitorsButton              matlab.ui.control.Button
        ErrorsTab                      matlab.ui.container.Tab
        txtErrors                      matlab.ui.control.TextArea
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        Tasks EyeTrackTasks
        NewTasks EyeTrackTasks
        Task EyeTrackTask
        NewTask EyeTrackTask
        Image EyeTrackImage
        NewImage EyeTrackImage % New Image Objects
        Aoi EyeTrackAOI
        Aois EyeTrackAOI
        NewAoi EyeTrackAOI
        NewAoiImage EyeTrackImage % Image container for new AOI(s)
        NewAoiTask EyeTrackTask % Task container for new AOI(s)
        taskNames
        Screen EyeTrackScreen
        Monitors
        aoiFigure
        aoiShape  
        libLocation
        monitor
        axImage
    end
    
    methods (Access = private)
        
        function changeAppPosition(app)
            % 6,57,949,631

             screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*(6/1280);
            bottom = screenHeight*(57/720);
            width = screenWidth*(949/1280);
            height = screenHeight*(631/720);
            drawnow;
            app.EyeTrackEditTasksFigure.Position = [left bottom width height];           
            
        end


        function success = loadLibraries(app)
            
            % Get location of text file containing storage location of libraries
            txtDir = fileparts(app.getcurrentdir);
            
            % Get directory from text file
            % location text file
            locText = fullfile(txtDir,'LibLocation.txt');
%             fileId = fopen(locText);
            
%             % If fileId = -1 file not found
%             if fileId == -1
%                 msgbox('Locations text file cannot be found');
%                 success = 0;
%                 return
%             else
% %                 libLoc = fscanf(fileId,'%s');
%             end

            try
                % Read the filepath from the text file
                libLoc = fileread(locText);
                app.libLocation = libLoc;
                
                % Load Tasks Library
                TaskLib = load(fullfile(libLoc,'EyeTrackTasksLib.mat'));
                app.Tasks = TaskLib.EyeTrackTasksLib;
                
                % Load monitors struct
                MonitorLib = load(fullfile(libLoc,'monitors.mat'));
                app.Monitors = MonitorLib.monitors;
                
            catch ME
                msgbox(ME.message);
                success = 0;
                return
            end
            
            success = 1;


        end
    
        function initializeProperties(app)
            
            app.Tasks = EyeTrackTasks;
            app.Task = EyeTrackTask;
            app.Image = EyeTrackImage;
            app.Aoi = EyeTrackAOI;
            app.Aois = EyeTrackAOI;  
            app.Screen = EyeTrackScreen;
            app.NewImage = EyeTrackImage;
            app.NewTask = EyeTrackTask;
            app.NewAoi=EyeTrackAOI;
            app.NewAoiImage=EyeTrackImage;
            app.NewAoiTask=EyeTrackTask;


        end
        
        function createTaskTree(app)
            
            app.taskNames = [app.Tasks.Items.taskName];  
        
            % Add to tree
            for iTask = 1:app.Tasks.Count
                % Get task
                app.Task = app.Tasks.Items(iTask);
                % Get Task Images
                Images = [app.Task.Images];
                % Task Node
                taskname = app.Task.taskName;
                tasknode = uitreenode(app.TreeTasks,'Text',taskname,...
                    'UserData',app.Task);
                 
                % Image nodes
                for jImg = 1:length(Images)
                    % Image object
                    app.Image = Images(jImg);
                    % Create image node
                    imagename = app.Image.imageID;
                    imagenode = uitreenode(tasknode,'Text',imagename,...
                        'UserData',app.Image);                    
                end
            end            
        end
        
        function loadImageToThumbnail(app,ImageObj)
            
            % Load image into thumbnail
                app.Image = ImageObj;
                app.Aois = [app.Image.Aois];
                
                
%                 position = app.Aoi.position;

                img = imread(app.Image.fileName);
%                 img = imread(app.img.FileName);
                rgbImage = img(:,:,1:3);
%                 imshow(rgbImage)
                imshow(rgbImage,'Parent',app.axThumbnail);

        end
        
        function updateAoiTable(app)
            
            % Get Aoi Names
            nameCol = {app.Aois.aoiName}';
            nAois = length(nameCol);
            
            % Get Aoi positions
            positions = {app.Aois.position};
            
            % Create empty cell arrays
            [xCol,yCol,widthCol,heightCol] = deal(cell(nAois,1));

            % position columns
            for iAoi = 1:nAois
                
                % x
                xCol{iAoi} = positions{iAoi}(1);
                
                % y
                yCol{iAoi} = positions{iAoi}(2);
                
                % width
                widthCol{iAoi} = positions{iAoi}(3);
                
                % height
                heightCol{iAoi} = positions{iAoi}(4);
            end
            
            % Create table
            tdata = table(nameCol,xCol,yCol,widthCol,heightCol);
            app.tblAOIs.Data = tdata;            
                
            
        end
        
        function createEmptyTable(app,tbl,size)
            

            if strcmpi(tbl.Tag,'Manual')
                
                tdata=table(...
                    strings(12,1),...
                    repmat({'Rectangle'},12,1),...
                    zeros(12,1),zeros(12,1),zeros(12,1),zeros(12,1));
                tbl.ColumnEditable = [true,false,true,true,true,true];
            else
                emptyTable = cell(size(1),size(2));            
                tdata = cell2table(emptyTable); 
            end

            tbl.Data = tdata;


        end
        
        function drawAoisThumbnail(app)
            
            % get index of selection
            app.ddDrawAOIs.ItemsData = 1:length(app.ddDrawAOIs.Items);
            idx = app.ddDrawAOIs.Value;
            
            % idx = 1 = '' idx = 2 = 'All' idx = 3 = table row1
            if idx == 1
                return
            elseif idx == 2
                % All
                app.NewAoi = [app.Image.Aois];
            else
                aoiIdx = idx-2;
                app.NewAoi = app.Image.Aois(aoiIdx);
            end

            % Draw aoi to thumbnail
            drawNewAoiPatches(app,app.axThumbnail);
            
            % Reset itemsData
            app.ddDrawAOIs.ItemsData = [];                

%             % If all, loop through Aois
%             % Multiple columns for each AOI position
%             % Else 1 column, idx = idx - 2 (1=empty,2='All')
%             if idx == 2 % All
%                 aoiIdx = (1:nAois);
%                 xData = zeros(4,nAois);
%                 yData = zeros(4,nAois);
%             else
%                 aoiIdx = idx-2;
%                 xData = zeros(4,1);
%                 yData = zeros(4,1);
%             end
%             
%             for iAoi = aoiIdx(1):aoiIdx(end)
%                 
%                 % Get x, y, width and height of shape
%                 x1 = cell2mat(table2cell(app.tblAOIs.Data(iAoi,2)));
%                 y1 = cell2mat(table2cell(app.tblAOIs.Data(iAoi,3)));
%                 w = cell2mat(table2cell(app.tblAOIs.Data(iAoi,4)));
%                 h = cell2mat(table2cell(app.tblAOIs.Data(iAoi,5)));
%                 
% %                 % x2 and y2 based on width and height
% %                 x2 = x1 + w;
% %                 y2 = y1 + h;
% %                 
% %                 % Create arrays of coordinates
% %                 x = [x1;x2;x2;x1];
% %                 y = [y1;y1;y2;y2];
%                 
%                 [x,y] = getPatchCoordinates([x1,y1,w,h]);
%                 
%                 % Save to array for plotting
%                 xData(:,iAoi) = x;
%                 yData(:,iAoi) = y;
                
%             end
%                 
%                 p1 = patch(xData,yData,'blue','visible','off','Parent',app.axThumbnail);
%                 p1.FaceAlpha=0.3;
%                 p1.LineStyle='none';
%                 p1.Visible='on';
%                 
%                 % Clear ItemsData
%                 app.ddDrawAOIs.ItemsData = [];                
        end
        
        function updateMonitorTable(app)
            
            % Monitor list in dropdown
%             
%             % Create table - default to first monitor
%             app.monitor = app.Monitors(1);
            
            % Fields
            screenResW = num2str(app.monitor.ScreenResolution(1));
            screenResH = num2str(app.monitor.ScreenResolution(2));
            screenRes = {strcat(screenResW,'x',screenResH)};
            diagonal = app.monitor.DiagonalCm;
            aspectX = app.monitor.AspectRatioX;
            aspectY = app.monitor.AspectRatioY;
            
            tdata = table(screenRes,diagonal,aspectX,aspectY);
            app.tblMonitor.Data = tdata;
        end
        
        function updateNewTask(app,append)
            
            % If append = 0 then clear new image and new task
            if append == 0
                app.NewImage = EyeTrackImage;
                app.NewTask.Images = EyeTrackImage;
                app.NewTask.Display = app.Screen;
            end
            
            % User chooses how to load
            loadType = app.ddChooseImageLoad.Value;
            
            % Get file/folder path
            fPath = app.txtImagePath.Value;
            
            if strcmpi(loadType,'From File')
                
                % Load image file
                ImageObj = EyeTrackImage;
                ImageObj = ImageObj.loadImageDetails(fPath);
                
                % Calculate display size of Image
                ImageObj = ImageObj.calculateDisplaySize(app.Screen);
                
                % Add NewImage to New Task
                if app.NewTask.Count == 0
                    app.NewImage = ImageObj;
                    app.NewTask.Images = ImageObj;   
                else
                    app.NewTask = app.NewTask.addImageObject(ImageObj);
                    app.NewImage = [app.NewTask.Images];
                end                
                
            else
                
                % Load All Images
                TaskObj = EyeTrackTask;
                TaskObj.Display = app.Screen;
                TaskObj = TaskObj.loadImageFromFolder(fPath);
%                 app.NewImage = [app.NewTask.Images];
                
                if app.NewTask.Count == 0
                    app.NewTask = TaskObj;
                    app.NewImage = [app.NewTask.Images];
                else
                    % Append images to end of current images
                    ImageArr = [TaskObj.Images];
                    
                    % Loop through new images and add to new task
                    for iImg = 1:length(ImageArr)
                        ImageObj = ImageArr(iImg);
                        app.NewTask = app.NewTask.addImageObject(ImageObj);
                        app.NewImage = [app.NewTask.Images];
                    end
                end
            end
            
%             % Update Table
%             app.updateImageTable(append);
            
        end
        
        function updateImageTable(app,append)
           
            if append == 0
                set(app.tblNewImages, 'Data', {});
            end

            % Number of images associated with New Task    
            nImages = app.NewTask.Count;
            
            % Get Image Names
            imageNames = {app.NewTask.Images.imageName}';
            
            % Image IDs (will be the same as image name
            % User can edit table
            imageIds = {app.NewTask.Images.imageID}';
            
            tdata = table(imageNames,imageIds);
            app.tblNewImages.Data = tdata;
            
        end
        
        function updateScreen(app)
            
            % Get monitor display
            monitorName = app.ddMonitor.Value;
            monitorIdx = find(strcmpi({app.Monitors.Name},monitorName));
            app.monitor = app.Monitors(monitorIdx);
            
            % Create Screen object
            app.Screen = EyeTrackScreen(...
                app.monitor.DiagonalCm,app.monitor.ScreenResolution,...
                [app.monitor.AspectRatioX app.monitor.AspectRatioY]);
            
            % Apply Screen to New Task
            app.NewTask.Display = app.Screen;  
            
        end
        
        function confirm = responseClearTask(app)
            
            % Confirm user wishes to change task
            response = uiconfirm(app.EyeTrackEditTasksFigure,...
                'Change item? Doing so will clear task details',...
                'Confirm Change Item','CloseFcn',@(src,event)taskChangeCallback(app,src,event));             
            
            if strcmpi(response,'OK')
                confirm = 1;
            else
                confirm = 0;
            end
            
        end
        
        function loadTasksImages(app,dd)
            
            % Add Tasks names to dropdown
            app.ddChooseTask.Items = {app.Tasks.Items.taskName};
            
            % Add Images to dropdown (default to 1)
            ImageObj = [app.Tasks.Items(1).Images];
            
            % Add Image IDs to dropdown
            app.ddChooseImage.Items = {ImageObj.imageID};
        end
        
        function aoiTabCreateFields(app)
            
            % Load Tasks and associated Images
            app.loadTasksImages;
            % App properties for new AOI
            app.NewAoiTask = app.Tasks.Items(1);
            app.NewAoiImage = app.NewAoiTask.Images(1);            
            
            % Create empty table
            
%             tdata = (array2table(zeros(1,4)));
%             app.tblAOIPosition.Data = tdata;
            
            
        end
        
        function createAoiUIFigure(app,drawAois)

            % Figure details
            if drawAois
                fig = uifigure('Visible','off','Name','Draw AOIs interactively','NumberTitle','off','Position', [500 42 681 642]);
            else
                fig = uifigure('Visible','off','Name','Display New AOIs','NumberTitle','off','Position', [500 42 681 642]);
            end
            
            fig.MenuBar = 'none';
            fig.Resize = 'off';
%             app.aoiFigure.PaperPosition = [20 20 100 100];
            fig.Position = app.EyeTrackEditTasksFigure.Position;
            fig.Color = [0.02,0.12,0.26];
            fig.Resize = 'off';
            
            % Set to app  property
            app.aoiFigure = fig;
            
            
            % Axes object
            app.axImage = axes(app.aoiFigure,'Position',[0.02 0 0.7 1]);
            disableDefaultInteractivity(app.axImage);
            app.aoiFigure.Visible = 'on';
            
            % Get figure position
            figPos = app.EyeTrackEditTasksFigure.Position;
            
            % Create 'New AOI' Button
            if drawAois
                new = uibutton(app.aoiFigure);
                pos1 = 138.5*figPos(1);
                pos2 = 9.6666667*figPos(2);
                pos3 = 0.10537*figPos(3);
                pos4 = 0.044374*figPos(4);            
    %             new.Position = [831,551,100,28];
                new.Position = [pos1,pos2,pos3,pos4];
                new.Text = 'New AOI';
                new.Tag = 'New';
                new.ButtonPushedFcn = @app.newAOIButtonPress;
                
                % Create Draw Mode label
                lbl = uilabel(app.aoiFigure);
    %             725,554,97,22
                p1 = new.Position(1);
                p2 = new.Position(2) + 41;
                p3 = new.Position(3);
                p4 = new.Position(4);
                lbl.Position = [p1,p2,p3,p4];
                lbl.Text = 'Draw Mode: OFF';
                lbl.FontColor = [1,1,1];
                lbl.HorizontalAlignment = 'center';
                lbl.Tag = 'DrawLabel';
                
                % Create 'Save' Button
                save = uibutton(app.aoiFigure);
                pos2 = 8.7018*figPos(2); 
                save.Position = [pos1,pos2,pos3,pos4];
                save.ButtonPushedFcn = @app.saveAOIButtonPress;
                save.Text = 'Save AOI';
                save.Tag = 'Save';
                
                % Create 'Clear' Button
                clear = uibutton(app.aoiFigure);
                pos2 = 7.1754*figPos(2);
    %             clear.Position = [831,440,100,28];
                clear.Position = [pos1,pos2,pos3,pos4];
                clear.Text = 'Clear Shape';
                clear.ButtonPushedFcn = @app.clearAOIButtonPress;
                clear.Tag = 'Clear';
                
                
                % Create Finish Button
                finish = uibutton(app.aoiFigure);
                pos2 = 0.491*figPos(2);
    %             cancel.Position = [831,28,100,28];
                finish.Position = [pos1,pos2,pos3,pos4];
                finish.Text = 'Finish';
                finish.Tag = 'Finish';
                finish.ButtonPushedFcn = @app.finishAOIButtonPress;
                
                % Create Cancel Button
                cancel = uibutton(app.aoiFigure);
                pos1 = finish.Position(1) - 0.131*figPos(3);
    %             cancel.Position = [831,28,100,28];
                cancel.Position = [pos1,pos2,pos3,pos4];
                cancel.Text = 'Cancel';
                cancel.Tag = 'Cancel';
                cancel.ButtonPushedFcn = @app.cancelAOIButtonPress;
            end
        end
        
        function saveAOIButtonPress(app,src,event)
            
            % Check there is a shape
            
            % Dialog box to prompt for aoi name
            aoiName = getAoiNameInput;
            
            % Add AOI to app.NewAoi array
            aoi = EyeTrackAOI;
            aoi.aoiName = string(aoiName{1});
            aoi.shape = lower(app.ddChooseAOIShape.Value);
            
            if isa(app.aoiShape,'images.roi.Rectangle') 
                
                aoi.position = app.aoiShape.Position;
                aoi.vertices = app.aoiShape.Vertices;
                
            elseif isa(app.aoiShape,'images.roi.Freehand')
        
                aoi.position = app.aoiShape.Position;
                aoi.vertices = aoi.position;
        
            elseif isa(app.aoiShape,'images.roi.Ellipse')
                center = app.aoiShape.Center;
                semiAx = app.aoiShape.SemiAxes;
                aoi.position = [center,semiAx];
                
                aoi.rotationAngle = app.aoiShape.RotationAngle;
                
                aoi.vertices = app.aoiShape.Vertices;
            end

            
            % Add to first position or end
            if isempty(app.NewAoi(1).aoiName)
                app.NewAoi = aoi;   
            else
                app.NewAoi = [app.NewAoi,aoi];
            end
            
            % Draw patch for new AOI
%             [x,y] = getPatchCoordinates(app.aoiShape);
            x = aoi.vertices(:,1);
            y = aoi.vertices(:,2);

            p1 = patch(x,y,'blue','visible','off','Parent',app.axImage);
            p1.FaceAlpha=0.3;
            p1.LineStyle='none';
            p1.Visible='on';
            
            % Delete app.aoiShape and reset label            
            app.aoiShape.delete;
            lbl = findobj(app.aoiFigure,'Type','uilabel');
            lbl.Text = 'Draw Mode: OFF';
        end
        
        function createAoiFigure(app)
            
%             % Figure details
%             app.aoiFigure = figure('Visible','off','Name','Draw AOIs interactively','NumberTitle','off','Position', [500 42 681 642]);
%             app.aoiFigure.MenuBar = 'none';
%             app.aoiFigure.Resize = 'off';
% %             app.aoiFigure.PaperPosition = [20 20 100 100];
%             app.aoiFigure.Position = app.EyeTrackEditTasksFigure.Position;
%             
%             % Axes object
%             app.axImage = axes(app.aoiFigure,'Position',[0 0 0.8 1]);
%             app.aoiFigure.Visible = 'on';            
        end
        
        function newAOIButtonPress(app,src,event)
            
            shape = app.ddChooseAOIShape.Value;
            
            % Change label
            lbl = findobj(app.aoiFigure,'Type','uilabel');
            lbl.Text = 'Draw Mode: ON';

            if strcmpi(shape,'rectangle')
                app.aoiShape = drawrectangle(app.axImage);
            elseif strcmpi(shape,'ellipse')
                app.aoiShape = drawellipse(app.axImage);
            else
                app.aoiShape = drawfreehand(app.axImage);
            end
            
        end
        
        function clearAOIButtonPress(app,src,event)
            app.aoiShape.delete;
            
            % Change label
            lbl = findobj(app.aoiFigure,'Type','uilabel');
            lbl.Text = 'Draw Mode: OFF';
            
        end
    
        function finishAOIButtonPress(app,src,event)
            
            % Create AOI Table
            updateNewAoiTable(app);
            % Close uiFigure
           app.aoiFigure.delete;
        end
    
        function cancelAOIButtonPress(app,src,event)
            app.aoiFigure.delete;
            app.NewAoi = EyeTrackAOI;
        end
        
        function saveNewTask(app)
            
            % Check whether new or existing task
            % If add to new and there is task name entered
            if isempty(app.txtTaskName.Value)
                msgbox('Please enter a new task name first')
                return
            end
                
            % Default answer = 'Yes'
            answer = 'Yes';
            
            % Check there are images - may add task without images
            if app.NewTask.Count == 0
                quest = 'No images loaded - add new task anyway?';
                answer = questdlg(quest,'Add Task',...
              'Yes','No','Cancel','Yes');
            end
            
            % If add task with/without images - confirm
            if strcmpi(answer,'Yes')

                taskName = app.txtTaskName.Value;

                quest = ['Confirm add ' taskName ' to library.'];
                answer = questdlg(quest,'Add Task',...
              'Confirm','Cancel','Confirm');
                
                % Check add new task
                if strcmp(answer,'Confirm')
                    % Add task Name and Type
                    app.NewTask.taskName = taskName;
                    app.NewTask.taskType = app.ddTaskType.Value;
                    % Add New Task to Tasks
                    app.Tasks = app.Tasks.addTaskObject(app.NewTask);
                    
                    % Update task tree
                    app.TreeTasks.Children.delete;
                    app.createTaskTree;

                else
                    return
                end
              
            else
                return
            end
            
            % Save Eyetracklib
            app.saveTasksLibrary;
            msgbox([taskName ' Added to Library']);
            
            % Reset new task and image properties
            app.NewTask = EyeTrackTask;
            app.NewImage = EyeTrackImage;            
           
        end
        
        function saveExistingTask(app)
            %% Add images to existing task
            
            % Check there are images - may add task without images
            if app.NewTask.Count == 0
                msgbox('Please load at least one image');
                return
            end
            
            % Confirm add images to task
            taskName = [' ' app.ddTaskForNewImages.Value ' '];

            if app.NewTask.Count > 1
                str = ['images'];
            else
                str = ['image'];
            end
            
            quest = ['Confirm add ' str ' to' taskName];
            answer = questdlg(quest,'Add Task',...
              'Confirm','Cancel','Confirm');
            
            % Check add new task
            if strcmpi(answer,'Cancel')
                return
            end            
            
            % Itemsdata for task idx
            app.ddTaskForNewImages.ItemsData = 1:app.Tasks.Count;
            
            % Tasks Item index
            taskIdx = app.ddTaskForNewImages.Value;
            TaskObj = app.Tasks.Items(taskIdx);
            
            % List of Images
            nImgs = app.NewTask.Count;
            
            % Loop and add to existing task
            for iImg = 1:nImgs
                ImageObj = app.NewImage(iImg);
                
                TaskObj = TaskObj.addImageObject(ImageObj);
            end
            
            % Save to Tasks list
            app.Tasks.Items(taskIdx) = TaskObj;
            
            % Save Eyetracklib
            app.saveTasksLibrary;
            msgbox([int2str(nImgs) ' ' str ' added to' taskName]);
            
            % Update task tree
            app.TreeTasks.Children.delete;
            app.createTaskTree;

            % Reset new task and image properties
            app.NewTask = EyeTrackTask;
            app.NewImage = EyeTrackImage;   


        end
        
        function saveTasksLibrary(app)
            
            libLoc = app.libLocation;
            saveName = fullfile(libLoc,'EyeTrackTasksLib.mat');
            EyeTrackTasksLib = app.Tasks;
            
            save(saveName,'EyeTrackTasksLib');
            
            % Clear data
            app.txtTaskName.Value = '';
            set(app.tblNewImages, 'Data', {});
            app.txtImagePath.Value = '';
            
%             msgbox('Done!');
            
            
        end
        
        function clearAOITable(app,tbl)
            
            set(tbl, 'Data', {});
            app.NewAoi = EyeTrackAOI;

            if strcmpi(tbl.Tag,'Manual')
                app.createEmptyTable(app.tblManualNewAOIs,[12,6]);
            end

        end
        
        function displayAoiFigure(app,drawAois)
            
            % If displaying AOIs (not drawing) and no
            % AOIs yet - return
            if ~drawAois && isempty(app.NewAoi(1).aoiName)
                msgbox('No AOIs created yet');
                return            
            end
            
            % Get image location
            fImg = app.NewAoiImage.fileName;
            
            % Read image
            img = imread(fImg);
            
            app.createAoiUIFigure(drawAois);
            
            % Load Image
            rgbImage = img(:,:,1:3);
            imshow(rgbImage,'Parent',app.axImage);
            
            % If displaying AOIs and there is at least one AOI
            % Draw AOI
            if ~drawAois && ~isempty(app.NewAoi(1).aoiName)
                drawNewAoiPatches(app,app.axImage);

            end
            
            
        end
        
        function addAoiToLibrary(app)
            
            nAois = numel(app.NewAoi);
            
            % Get Task index
            app.ddChooseTask.ItemsData = 1:app.Tasks.Count;
            taskIdx = app.ddChooseTask.Value;
            
            % Get Image Index
            app.ddChooseImage.ItemsData = 1:app.NewAoiTask.Count;
            imgIdx = app.ddChooseImage.Value;
            
            % Image object
            ImageObj = app.NewAoiImage;

            % Add each aoi to image
            for iAoi = 1:nAois
                
                % New aoi
                aoi = app.NewAoi(iAoi);
                
                
                % Add Aoi
                ImageObj = ImageObj.addAOIObject(aoi);
                
            end
            
            % Update NewAoiImage
            app.NewAoiImage = ImageObj;
            % Replace Image in Tasks Collection
            app.Tasks.Items(taskIdx).Images(imgIdx) = ImageObj;            
        end
    end
    
    methods (Access = public)
        
       function currentDir = getcurrentdir(app)
           
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end     
       end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.EyeTrackEditTasksFigure.Visible = 'off';
            % Resize based on screen resolution
            app.changeAppPosition;
            
            % Initialize properties
            app.initializeProperties;
            
            % Create empty table
            app.createEmptyTable(app.tblAOIs,[5,5]);
            
            % Load libraries
            loaded = app.loadLibraries;
            
            if loaded
                % Build task Tree
                app.createTaskTree;
                
                % Default monitor to monitor 1
                % Update monitors table
                app.ddMonitor.Items = {app.Monitors.Name};
                app.updateScreen;
                app.updateMonitorTable;
            end
            
            set(app.tblManualNewAOIs,'Tooltip','');
            
            % Set app to visible
            app.EyeTrackEditTasksFigure.Visible = 'on';
        end

        % Selection changed function: TreeTasks
        function TreeTasksSelectionChanged(app, event)
            
            selectedNodes = app.TreeTasks.SelectedNodes;
            
            userdata = selectedNodes.UserData;
            
            if isa(userdata,'EyeTrackImage') == 1

                try
                    % Set Image property
                    app.Image = userdata;
                    
                    % Display image in thumbnail
                    app.loadImageToThumbnail(userdata);
                    
                    % Update AOI table to reflect AOIs associated with image
                    app.updateAoiTable;
                    
                    % Update AOi drop down
                    aoiNames = [app.Aois.aoiName];
                    app.ddDrawAOIs.Items = ['','All',aoiNames];
                    app.ddDrawAOIs.Value = '';
                catch ME
                    app.txtErrors.Value = '';
                    app.txtErrors.Value =...
                        [ME.message ' at line ' int2str(ME.stack(1).line)];
                end
            elseif isa(userdata,'EyeTrackTask') == 1
                app.Task = userdata;
                
            end
        end

        % Value changed function: ddDrawAOIs
        function ddDrawAOIsValueChanged(app, event)
            value = app.ddDrawAOIs.Value;
            
            % Redraw image
            app.loadImageToThumbnail(app.Image);
            
            % Draw Aois 
            app.drawAoisThumbnail;
                
        end

        % Button pushed function: btnGetImagePath
        function btnGetImagePathButtonPushed(app, event)
            
            pathSelection = app.ddChooseImageLoad.Value;
            
            append = 1;
            
            % Check if there are already images in table
            if ~isempty(app.tblNewImages.Data)
                % Check first that user wishes to add images if they already exist
                answer = questdlg('Append to images or replace?', ...
                	'', ...
                	'Append','Replace','Append');
                % Handle response
                switch answer
                    case 'Append'
                        append = 1;
                    case 'Replace'
                        append = 0;
                end                

            end
                
            app.EyeTrackEditTasksFigure.Visible = 'off';
                
            if strcmpi(pathSelection,'From File')
                % Get path of image file
                [fName,fPath] = uigetfile( ...
                    {'*.bmp;*.gif;*.jpeg;*.jpg;*.pbm;*.png;*.tif;*.tiff',...
                        'Image Files (*.bmp,*.gif,*.jpeg,*.jpg,*.pbm,*.png,*.tif,*.tiff)'}, ...
                       'Select a File');
                   
                % Full file name
                fPath = fullfile(fPath,fName);
                
            else
                % Get path of image folder
                fPath = uigetdir;
            end
                
            % Show text in box
            if fPath ~= 0
                app.txtImagePath.Value = fPath;
            else 
                app.EyeTrackEditTasksFigure.Visible = 'on';
                return
            end
                
            
            % Update New Task
            app.updateNewTask(append);
            
            % Update Image Table
            app.updateImageTable(append);
            
            app.EyeTrackEditTasksFigure.Visible = 'on';
    
        end

        % Value changed function: ddMonitor
        function ddMonitorValueChanged(app, event)
            value = app.ddMonitor.Value;
            
            % Check if there are details in the Image table
            if ~isempty(app.tblNewImages.Data)
                response = app.responseClearTask;
                
                if response == 0
                    app.ddMonitor.Value = event.PreviousValue;
                    return
                end
            end
            
            % Update Screen Object
            app.updateScreen;
            
            % Update table
            app.updateMonitorTable;
            

        end

        % Value changed function: txtTaskName
        function txtTaskNameValueChanged(app, event)
            value = app.txtTaskName.Value;
            
            % Save Task Name
            app.NewTask.taskName = value;
            app.NewTask.taskType = app.ddTaskType.Value;
            
%             % Update task drop down for loading images
%             % set new task as default
%             ddItems = app.ddTaskForNewImages.Items;
%             
%             app.ddTaskForNewImages.Items = [ddItems,{app.NewTask.taskName}];
            
        end

        % Value changed function: ddTaskType
        function ddTaskTypeValueChanged(app, event)
            value = app.ddTaskType.Value;
            
            % Check if there are details in the Image table
            if ~isempty(app.tblNewImages.Data)
                response = app.responseClearTask;
                
                if response == 0
                    app.ddTaskType.Value = event.PreviousValue;
                    return
                end
            end
            
            if strcmpi(value,'Static')
                app.NewTask.taskType = "STATIC";
            else
                app.NewTask.taskType = "GAZE_CONTINGENT";
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            % Refresh new AOIs
            app.NewAoi = EyeTrackAOI;
            
            if strcmpi(selectedTab.Title,'Add Aois')
                
                % Create empty tables
                app.createEmptyTable(app.tblNewAOIs,[5,6]);
                app.tblManualNewAOIs.Tag = 'Manual';
                app.tblManualNewAOIs.Tooltip = {''};
                app.createEmptyTable(app.tblManualNewAOIs,[12,6]);
                app.aoiTabCreateFields;
            
            elseif strcmpi(selectedTab.Title,'Add New Task and Images')
                
                app.btnAddImagesToNew.Value = 1;
                
                app.ddTaskForNewImages.Visible = 'off';
                app.ddTaskForNewImages.Items = {app.Tasks.Items.taskName};
            
            end
        end

        % Value changed function: ddChooseTask
        function ddChooseTaskValueChanged(app, event)
            value = app.ddChooseTask.Value;
            
            % Get index of Task
            app.ddChooseTask.ItemsData = 1:length(app.ddChooseTask.Items);
            idx = app.ddChooseTask.Value;
            
            % New AOI Task
            app.NewAoiTask = app.Tasks.Items(idx);

            % New AOI Image
            app.NewAoiImage = app.NewAoiTask.Images(1);
            
            % Image Object
            ImageObj = [app.NewAoiTask.Images];
            
            % Add new image IDs to dropdown
            app.ddChooseImage.Items = {ImageObj.imageID};
        end

        % Value changed function: ddChooseImage
        function ddChooseImageValueChanged(app, event)
            value = app.ddChooseImage.Value;
            
            % Get index of Task
            app.ddChooseImage.ItemsData = 1:length(app.ddChooseImage.Items);
            idx = app.ddChooseImage.Value;
            
            % New AOI Image
            app.NewAoiImage = app.NewAoiTask.Images(idx);
            
        end

        % Button pushed function: btnLoadImageForAOIUI
        function btnLoadImageForAOIUIButtonPushed(app, event)
            
            % Default answer
            answer = 'Add';
            
            if ~isempty(app.NewAoi(1).aoiName)
            
                quest = 'Add new AOI to list or replace?';
                answer = questdlg(quest,'Draw New AOI',...
                'Add','Replace','Cancel','Add');
            end
            
            if strcmpi(answer,'Cancel')
                return
            elseif strcmpi(answer,'Replace')
                app.clearAOITable;
            end
                
            app.displayAoiFigure(1);

        end

        % Selection changed function: btnGrpAddImages
        function btnGrpAddImagesSelectionChanged(app, event)
            selectedButton = app.btnGrpAddImages.SelectedObject;
            
            g = app.CreateNewTaskPanel.Children.findobj;

            if app.btnAddImagesToNew.Value
                app.ddTaskForNewImages.Visible = 'off';
                app.lblSelectTaskImages.Visible = 'off';
                
                set(g,'Enable','on');
            else                
                app.ddTaskForNewImages.Visible = 'on';
                app.lblSelectTaskImages.Visible = 'on';
                
                set(g,'Enable','off');
            end

        end

        % Button pushed function: btnClearImageTable
        function btnClearImageTableButtonPushed(app, event)
            
%             app.tblNewImages.Data = table({''},{''});
            set(app.tblNewImages, 'Data', {});
            app.NewTask = EyeTrackTask;
            app.NewImage = EyeTrackImage;

        end

        % Button pushed function: btnSaveNewTask
        function btnSaveNewTaskButtonPushed(app, event)
            
            % If add to new or existing task
            if app.btnAddImagesToNew.Value
                app.saveNewTask;
            elseif app.btnAddImagesToExisting.Value
                app.saveExistingTask;
            end
            
            % Update AOI tab
            app.aoiTabCreateFields;
            
        end

        % Cell edit callback: tblNewImages
        function tblNewImagesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Get table row (image index) and new ID
            imageIdx = indices(1);
            newId = newData;
            
            % Add new ID to Image
            app.NewTask.Images(imageIdx).imageID = newId;
            app.NewImage(imageIdx).imageID = newId;
            
        end

        % Value changed function: ddChooseAOIEntry
        function ddChooseAOIEntryValueChanged(app, event)
            value = app.ddChooseAOIEntry.Value;
%             
            % Reset New AOIs
            app.NewAoi = EyeTrackAOI;
            
            % Objects in panel for enabling/disabling
            gDraw = app.DrawAOIInteractivelyPanel.Children.findobj;
            gManual = app.ManualAOIPanel.Children.findobj;
            
            if strcmpi(value,'Draw')
                set(gDraw,'Enable','on');
                set(gManual,'Enable','off');
                app.ddChooseAOIShape.Items = {...
                    'Rectangle','Ellipse','Freehand'};
            else
                app.ddChooseAOIShape.Items = {'Rectangle'};
                set(gDraw,'Enable','off');
                set(gManual,'Enable','on');
            end                
        end

        % Button pushed function: btnClearAOITable
        function btnClearAOITableButtonPushed(app, event)
            app.clearAOITable(app.tblNewAOIs);
            app.createEmptyTable(app.tblNewAOIs);
        end

        % Button pushed function: btnDisplayDrawnAois
        function btnDisplayDrawnAoisButtonPushed(app, event)
            
            app.displayAoiFigure(0);
        end

        % Button pushed function: btnSaveNewAOIs
        function btnSaveNewAOIsButtonPushed(app, event)
            
            nAois = numel(app.NewAoi);
            
            % Add AOIs to library
            app.addAoiToLibrary;
            
            % Clear AOIs from table
            app.clearAOITable(app.tblNewAOIs);
            
            % Update task tree
            app.TreeTasks.Children.delete;
            app.createTaskTree;

            % Save new library
            app.saveTasksLibrary;

            % Confirmation
            if nAois > 1
                msgbox([int2str(aoiCtr) ' AOIs added to libary']);
            else
                msgbox([int2str(aoiCtr) ' AOI added to libary']);                    
            end


        end

        % Cell edit callback: tblManualNewAOIs
        function tblManualNewAOIsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            row = indices(1);
            col = indices(2);
            
            if col == 1
                app.tblManualNewAOIs.Data(row,col) = {""};
                app.tblManualNewAOIs.Data(row,col) = {newData};
            end
        end

        % Button pushed function: btnClearManualAOITable
        function btnClearManualAOITableButtonPushed(app, event)
            app.clearAOITable(app.tblManualNewAOIs);
        end

        % Button pushed function: btnDisplayManualAois
        function btnDisplayManualAoisButtonPushed(app, event)
            
            % Create New AOIs
            app.NewAoi = EyeTrackAOI;
            aoiCtr = addManualAoi(app,app.tblManualNewAOIs);
            
            if aoiCtr == 0
                msgbox('No AOIs added - check for missing data')
                return
            else
                % Display AOIs
                app.displayAoiFigure(0);            
            end


        end

        % Button pushed function: btnSaveManualNewAOIs
        function btnSaveManualNewAOIsButtonPushed(app, event)
            
            % Create New AOIs
            app.NewAoi = EyeTrackAOI;
            aoiCtr = addManualAoi(app,app.tblManualNewAOIs);
            
            if aoiCtr == 0
                msgbox('No AOIs added - check for missing data')
                return
            else
            
                % Get AOIs
                
                % Add AOIs to library
                app.addAoiToLibrary;        
                
                % Clear AOIs from table
                app.clearAOITable(app.tblManualNewAOIs);
                
                % Save new library
                app.saveTasksLibrary;
                
                % Update task tree
                app.TreeTasks.Children.delete;
                app.createTaskTree;
                
                if aoiCtr > 1
                    msgbox([int2str(aoiCtr) ' AOIs added to libary']);
                else
                    msgbox([int2str(aoiCtr) ' AOI added to libary']);                    
                end
            end
            

        end

        % Button pushed function: getLibButton
        function getLibButtonPushed(app, event)
            % Load Tasks Library
            
            libLoc = uigetdir;
            TaskLib = load(fullfile(libLoc,'EyeTrackTasksLib.mat'));
            app.Tasks = TaskLib.EyeTrackTasksLib;
            
%             % Load monitors struct
%             MonitorLib = load(fullfile(libLoc,'monitors.mat'));
%             app.Monitors = MonitorLib.monitors;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EyeTrackEditTasksFigure = uifigure;
            app.EyeTrackEditTasksFigure.Name = 'Eye Track Edit Tasks';
            app.TabGroup = uitabgroup(app.EyeTrackEditTasksFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.tabViewTasks = uitab(app.TabGroup);
            app.tabViewTasks.Title = 'View';
            app.TreeTasks = uitree(app.tabViewTasks);
            app.TreeTasks.SelectionChangedFcn = createCallbackFcn(app, @TreeTasksSelectionChanged, true);
            app.axThumbnail = uiaxes(app.tabViewTasks);
            app.axThumbnail.XTick = [];
            app.axThumbnail.YTick = [];
            app.lblTaskTree = uilabel(app.tabViewTasks);
            app.lblTaskTree.Text = 'List of Tasks and Associated Images';
            app.tblAOIs = uitable(app.tabViewTasks);
            app.tblAOIs.ColumnName = {'AOI Name'; 'x'; 'y'; 'width'; 'height'};
            app.tblAOIs.RowName = {};
            app.SelectAOIstoDisplayLabel = uilabel(app.tabViewTasks);
            app.SelectAOIstoDisplayLabel.Text = {'Select AOIs to Display'; ''};
            app.ddDrawAOIs = uidropdown(app.tabViewTasks);
            app.ddDrawAOIs.Items = {};
            app.ddDrawAOIs.ValueChangedFcn = createCallbackFcn(app, @ddDrawAOIsValueChanged, true);
            app.ddDrawAOIs.Value = {};
            app.AddNewTaskandImagesTab = uitab(app.TabGroup);
            app.AddNewTaskandImagesTab.Title = 'Add New Task and Images';
            app.CreateNewTaskPanel = uipanel(app.AddNewTaskandImagesTab);
            app.CreateNewTaskPanel.Title = 'Create New Task';
            app.TaskTypeDropDownLabel = uilabel(app.CreateNewTaskPanel);
            app.TaskTypeDropDownLabel.Text = 'Task Type';
            app.ddTaskType = uidropdown(app.CreateNewTaskPanel);
            app.ddTaskType.Items = {'Static', 'Gaze Contingent'};
            app.ddTaskType.ValueChangedFcn = createCallbackFcn(app, @ddTaskTypeValueChanged, true);
            app.ddTaskType.Value = 'Static';
            app.TaskNameEditFieldLabel = uilabel(app.CreateNewTaskPanel);
            app.TaskNameEditFieldLabel.Text = 'Task Name';
            app.txtTaskName = uieditfield(app.CreateNewTaskPanel, 'text');
            app.txtTaskName.ValueChangedFcn = createCallbackFcn(app, @txtTaskNameValueChanged, true);
            app.ChooseMonitorDropDownLabel = uilabel(app.CreateNewTaskPanel);
            app.ChooseMonitorDropDownLabel.Text = 'Choose Monitor';
            app.ddMonitor = uidropdown(app.CreateNewTaskPanel);
            app.ddMonitor.Items = {};
            app.ddMonitor.ValueChangedFcn = createCallbackFcn(app, @ddMonitorValueChanged, true);
            app.ddMonitor.Value = {};
            app.tblMonitor = uitable(app.CreateNewTaskPanel);
            app.tblMonitor.ColumnName = {'Screen Resolution'; 'Diagonal cm'; 'Aspect Ratio X'; 'Aspect Ratio Y'};
            app.tblMonitor.RowName = {};
            app.tblMonitor.ColumnEditable = [false false true];
            app.AddImagestoTaskPanel = uipanel(app.AddNewTaskandImagesTab);
            app.AddImagestoTaskPanel.Title = 'Add Images to Task';
            app.ChooseLoadOptionDropDownLabel = uilabel(app.AddImagestoTaskPanel);
            app.ChooseLoadOptionDropDownLabel.Text = 'Choose Load Option';
            app.ddChooseImageLoad = uidropdown(app.AddImagestoTaskPanel);
            app.ddChooseImageLoad.Items = {'From File', 'From Folder'};
            app.ddChooseImageLoad.Tooltip = {'Choose From File to add a single image. Choose From Folder to import batch of images'};
            app.ddChooseImageLoad.Value = 'From File';
            app.PathLabel = uilabel(app.AddImagestoTaskPanel);
            app.PathLabel.Text = 'Path';
            app.txtImagePath = uieditfield(app.AddImagestoTaskPanel, 'text');
            app.btnGetImagePath = uibutton(app.AddImagestoTaskPanel, 'push');
            app.btnGetImagePath.ButtonPushedFcn = createCallbackFcn(app, @btnGetImagePathButtonPushed, true);
            app.btnGetImagePath.Text = '...';
            app.btnSaveNewTask = uibutton(app.AddImagestoTaskPanel, 'push');
            app.btnSaveNewTask.ButtonPushedFcn = createCallbackFcn(app, @btnSaveNewTaskButtonPushed, true);
            app.btnSaveNewTask.Text = 'Save';
            app.tblNewImages = uitable(app.AddImagestoTaskPanel);
            app.tblNewImages.ColumnName = {'Image File Name'; 'Image ID'};
            app.tblNewImages.RowName = {};
            app.tblNewImages.ColumnEditable = [false true];
            app.tblNewImages.CellEditCallback = createCallbackFcn(app, @tblNewImagesCellEdit, true);
            app.ddTaskForNewImages = uidropdown(app.AddImagestoTaskPanel);
            app.ddTaskForNewImages.Items = {};
            app.ddTaskForNewImages.Visible = 'off';
            app.ddTaskForNewImages.Value = {};
            app.btnGrpAddImages = uibuttongroup(app.AddImagestoTaskPanel);
            app.btnGrpAddImages.SelectionChangedFcn = createCallbackFcn(app, @btnGrpAddImagesSelectionChanged, true);
            app.btnAddImagesToExisting = uiradiobutton(app.btnGrpAddImages);
            app.btnAddImagesToExisting.Text = 'Add Images to Existing Task';
            app.btnAddImagesToNew = uiradiobutton(app.btnGrpAddImages);
            app.btnAddImagesToNew.Text = 'Add Images to New Task';
            app.btnAddImagesToNew.Value = true;
            app.lblSelectTaskImages = uilabel(app.AddImagestoTaskPanel);
            app.lblSelectTaskImages.Visible = 'off';
            app.lblSelectTaskImages.Text = 'Select Task';
            app.btnClearImageTable = uibutton(app.AddImagestoTaskPanel, 'push');
            app.btnClearImageTable.ButtonPushedFcn = createCallbackFcn(app, @btnClearImageTableButtonPushed, true);
            app.btnClearImageTable.Text = 'Clear Table';
            app.AddAOIsTab = uitab(app.TabGroup);
            app.AddAOIsTab.Title = 'Add AOIs';
            app.TaskImageandAOIDetailsPanel = uipanel(app.AddAOIsTab);
            app.TaskImageandAOIDetailsPanel.Title = 'Task, Image and AOI Details';
            app.ChooseTaskLabel = uilabel(app.TaskImageandAOIDetailsPanel);
            app.ChooseTaskLabel.Text = 'Choose Task';
            app.ddChooseTask = uidropdown(app.TaskImageandAOIDetailsPanel);
            app.ddChooseTask.Items = {};
            app.ddChooseTask.ValueChangedFcn = createCallbackFcn(app, @ddChooseTaskValueChanged, true);
            app.ddChooseTask.Value = {};
            app.ChooseImageLabel = uilabel(app.TaskImageandAOIDetailsPanel);
            app.ChooseImageLabel.Text = 'Choose Image';
            app.ddChooseImage = uidropdown(app.TaskImageandAOIDetailsPanel);
            app.ddChooseImage.Items = {};
            app.ddChooseImage.ValueChangedFcn = createCallbackFcn(app, @ddChooseImageValueChanged, true);
            app.ddChooseImage.Value = {};
            app.AOIEntryMethodLabel = uilabel(app.TaskImageandAOIDetailsPanel);
            app.AOIEntryMethodLabel.Text = 'AOI Entry Method';
            app.ddChooseAOIEntry = uidropdown(app.TaskImageandAOIDetailsPanel);
            app.ddChooseAOIEntry.Items = {'Draw', 'Enter manually', 'Copy from existing'};
            app.ddChooseAOIEntry.ValueChangedFcn = createCallbackFcn(app, @ddChooseAOIEntryValueChanged, true);
            app.ddChooseAOIEntry.Value = 'Draw';
            app.AOIShapeLabel = uilabel(app.TaskImageandAOIDetailsPanel);
            app.AOIShapeLabel.Text = 'AOI Shape';
            app.ddChooseAOIShape = uidropdown(app.TaskImageandAOIDetailsPanel);
            app.ddChooseAOIShape.Items = {'Rectangle', 'Ellipse', 'Freehand'};
            app.ddChooseAOIShape.Value = 'Rectangle';
            app.DrawAOIInteractivelyPanel = uipanel(app.AddAOIsTab);
            app.DrawAOIInteractivelyPanel.Title = 'Draw AOI Interactively';
            app.btnLoadImageForAOIUI = uibutton(app.DrawAOIInteractivelyPanel, 'push');
            app.btnLoadImageForAOIUI.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageForAOIUIButtonPushed, true);
            app.btnLoadImageForAOIUI.Text = 'Load Image';
            app.tblNewAOIs = uitable(app.DrawAOIInteractivelyPanel);
            app.tblNewAOIs.ColumnName = {'AOI Name'; 'Shape'; 'x'; 'y'; 'width'; 'height'};
            app.tblNewAOIs.RowName = {};
            app.tblNewAOIs.Tooltip = {'Rectangle: '; 'x1'; 'y1'; 'width'; 'height'; ''; 'Ellipse: '; 'centreX'; 'centreY'; 'semiAxesX'; 'semiAxesY'; ''; 'Freehand:'; 'minX'; 'minY'; 'maxWidth'; 'maxHeight'};
            app.btnSaveNewAOIs = uibutton(app.DrawAOIInteractivelyPanel, 'push');
            app.btnSaveNewAOIs.ButtonPushedFcn = createCallbackFcn(app, @btnSaveNewAOIsButtonPushed, true);
            app.btnSaveNewAOIs.Text = 'Save AOIs';
            app.btnClearAOITable = uibutton(app.DrawAOIInteractivelyPanel, 'push');
            app.btnClearAOITable.ButtonPushedFcn = createCallbackFcn(app, @btnClearAOITableButtonPushed, true);
            app.btnClearAOITable.Text = 'Clear AOIs';
            app.btnDisplayDrawnAois = uibutton(app.DrawAOIInteractivelyPanel, 'push');
            app.btnDisplayDrawnAois.ButtonPushedFcn = createCallbackFcn(app, @btnDisplayDrawnAoisButtonPushed, true);
            app.btnDisplayDrawnAois.Text = 'Display New AOIs';
            app.ManualAOIPanel = uipanel(app.AddAOIsTab);
            app.ManualAOIPanel.Title = 'Edit AOI (Manual/Copy)';
            app.tblManualNewAOIs = uitable(app.ManualAOIPanel);
            app.tblManualNewAOIs.ColumnName = {'AOI Name'; 'Shape'; 'x'; 'y'; 'width'; 'height'};
            app.tblManualNewAOIs.RowName = {};
            app.tblManualNewAOIs.ColumnEditable = false;
            app.tblManualNewAOIs.CellEditCallback = createCallbackFcn(app, @tblManualNewAOIsCellEdit, true);
            app.tblManualNewAOIs.Tooltip = {'Rectangle: '; 'x1'; 'y1'; 'width'; 'height'; ''; 'Ellipse: '; 'centreX'; 'centreY'; 'semiAxesX'; 'semiAxesY'; ''; 'Freehand:'; 'minX'; 'minY'; 'maxWidth'; 'maxHeight'};
            app.tblManualNewAOIs.Enable = 'off';
            app.btnSaveManualNewAOIs = uibutton(app.ManualAOIPanel, 'push');
            app.btnSaveManualNewAOIs.ButtonPushedFcn = createCallbackFcn(app, @btnSaveManualNewAOIsButtonPushed, true);
            app.btnSaveManualNewAOIs.Enable = 'off';
            app.btnSaveManualNewAOIs.Text = 'Save AOIs';
            app.btnClearManualAOITable = uibutton(app.ManualAOIPanel, 'push');
            app.btnClearManualAOITable.ButtonPushedFcn = createCallbackFcn(app, @btnClearManualAOITableButtonPushed, true);
            app.btnClearManualAOITable.Enable = 'off';
            app.btnClearManualAOITable.Text = 'Clear AOIs';
            app.btnDisplayManualAois = uibutton(app.ManualAOIPanel, 'push');
            app.btnDisplayManualAois.ButtonPushedFcn = createCallbackFcn(app, @btnDisplayManualAoisButtonPushed, true);
            app.btnDisplayManualAois.Text = 'Display New AOIs';
            app.CopyAOIsTab = uitab(app.TabGroup);
            app.CopyAOIsTab.Title = 'Copy AOIs';
            app.AddNewMonitorTab = uitab(app.TabGroup);
            app.AddNewMonitorTab.Title = 'Add New Monitor';
            app.Button = uibutton(app.AddNewMonitorTab, 'push');
            app.DrawModeOFFLabel = uilabel(app.AddNewMonitorTab);
            app.DrawModeOFFLabel.Text = 'Draw Mode: OFF';
            app.Button_2 = uibutton(app.AddNewMonitorTab, 'push');
            app.getLibButton = uibutton(app.AddNewMonitorTab, 'push');
            app.getLibButton.ButtonPushedFcn = createCallbackFcn(app, @getLibButtonPushed, true);
            app.getLibButton.Text = 'getLib';
            app.getMonitorsButton = uibutton(app.AddNewMonitorTab, 'push');
            app.getMonitorsButton.Text = 'getMonitors';
            app.ErrorsTab = uitab(app.TabGroup);
            app.ErrorsTab.Title = 'Errors';
            app.txtErrors = uitextarea(app.ErrorsTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = EyeTrackEditTasks

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EyeTrackEditTasksFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EyeTrackEditTasksFigure)
        end
    end
end