classdef EyeTrackLibraryEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EyeTrackLibraryEditFigure  matlab.ui.Figure
        TreeTasks                  matlab.ui.container.Tree
        lblTaskTree                matlab.ui.control.Label
        axThumbnail                matlab.ui.control.UIAxes
        btnEditLibItem             matlab.ui.control.Button
        btnDeleteLibItem           matlab.ui.control.Button
        btnSaveLibrary             matlab.ui.control.Button
        btnReloadLibrary           matlab.ui.control.Button
        btnAoiUp                   matlab.ui.control.Button
        btnAoiDown                 matlab.ui.control.Button
        AOILabel                   matlab.ui.control.Label
        PositionLabel              matlab.ui.control.Label
        lblWait                    matlab.ui.control.Label
    end

    
    properties (Access = private)
%         Tasks EyeTrackTasks 
        Task EyeTrackTask
        Image EyeTrackImage
        Aoi EyeTrackAOI
        libLocation
        taskNames
    end
    
    properties (Access = public)
        NewAoi EyeTrackAOI
        Tasks EyeTrackTasks         
    end
    
    methods (Access = private)
        
        function createTaskTree(app)
            
            app.taskNames = [app.Tasks.Items.taskName];  
        
            % Add to tree
            for iTask = 1:app.Tasks.Count
                % Get task
                app.Task = app.Tasks.Items(iTask);
                % Get Task Images
                Images = [app.Task.Images];
                % Task Node
                taskname = app.Task.taskName;
                tasknode = uitreenode(app.TreeTasks,'Text',taskname,...
                    'UserData',app.Task);
                 
                % Image nodes
                for jImg = 1:length(Images)
                    % Image object
                    app.Image = Images(jImg);
                    
                    % List of Aois for image
                    AoiList = [app.Image.Aois];
                    % Create image node
                    imagename = app.Image.imageID;
                    imagenode = uitreenode(tasknode,'Text',imagename,...
                        'UserData',app.Image);  
                    
                    % Aoi Nodes
                    for kAoi = 1:length(AoiList)
                        app.Aoi = AoiList(kAoi);
                        % Create Aoi node
                        aoiname = app.Aoi.aoiName;
                        aoinode = uitreenode(imagenode,'Text',aoiname,...
                            'UserData',app.Aoi);
                    end
                    
                end
                
            end 
            
        end    

        function initializeProperties(app)
            
            app.Tasks = EyeTrackTasks;
            app.Task = EyeTrackTask;
            app.Image = EyeTrackImage;
            app.Aoi = EyeTrackAOI;
            
        end
    
        function success = loadLibraries(app)
            
            % Get location of text file containing storage location of libraries
            txtDir = fileparts(app.getcurrentdir);
            
            % Get directory from text file
            % location text file
            locText = fullfile(txtDir,'LibLocation.txt');

            try
                % Read the filepath from the text file
                libLoc = fileread(locText);
                app.libLocation = libLoc;
                
                % Load Tasks Library
                TaskLib = load(fullfile(libLoc,'EyeTrackTasksLib.mat'));
                app.Tasks = TaskLib.EyeTrackTasksLib;
                
            catch ME
                msgbox(ME.message);
                success = 0;
                return
            end
            
            success = 1;
            
        end
    
        function currentDir = getcurrentdir(app)
        
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end     
        end
    
        function loadImageToThumbnail(app,ImageObj)
            
            % Load image into thumbnail
                app.Image = ImageObj;
                app.Aoi = [app.Image.Aois];
        %                 position = app.Aoi.position;
        
                img = imread(app.Image.fileName);
        %                 img = imread(app.img.FileName);
                rgbImage = img(:,:,1:3);
        %                 imshow(rgbImage)
                imshow(rgbImage,'Parent',app.axThumbnail);
        
        end
        
        function drawAoisThumbnail(app)
            
            % get index of selection
            app.ddDrawAOIs.ItemsData = 1:length(app.ddDrawAOIs.Items);
            idx = app.ddDrawAOIs.Value;
            
            % idx = 1 = '' idx = 2 = 'All' idx = 3 = table row1
            if idx == 1
                return
            elseif idx == 2
                % All
                app.NewAoi = [app.Image.Aois];
            else
                aoiIdx = idx-2;
                app.NewAoi = app.Image.Aois(aoiIdx);
            end

            % Draw aoi to thumbnail
            drawNewAoiPatches(app,app.axThumbnail);
               
        end
        
        function saveTasksLibrary(app)
            
            % Ensure user wants to overwrite library
            f = app.EyeTrackLibraryEditFigure;
            msg = 'Saving any changes will overwrite existing library.';
            title = 'Confirm Save Library';
            response = uiconfirm(f,msg,title,...
                       'Options',{'Overwrite','Cancel'},...
                       'DefaultOption',1,'CancelOption',2);            

            if strcmpi(response,'Overwrite')
                app.lblWait.Visible = 'on';
                pause(0.2);
                % Get library location
                libLoc = app.libLocation;
                saveName = fullfile(libLoc,'EyeTrackTasksLib.mat');
                EyeTrackTasksLib = app.Tasks;
                
                % Save new library
                save(saveName,'EyeTrackTasksLib');
                app.lblWait.Text = 'Done.';
                pause(5);
                app.lblWait.Visible = 'off';
                app.lblWait.Text = 'Please wait...';
    
            end            
            
            
        end  
        
        function changeAppPosition(app)
            % 6,57,949,631

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*(6/1280);
            bottom = screenHeight*(57/720);
            width = screenWidth*(949/1280);
            height = screenHeight*(631/720);
            drawnow;
            app.EyeTrackLibraryEditFigure.Position = [left bottom width height];           
            
        end    
    
        
        function changeControlPositions(app)
        
%             f = app.EyeTrackLibraryEditFigure;
%             controlStruct = load('LibEditCtrlPos.mat');
%             controlStruct = controlStruct.controlStruct;      
%         
%             objList = [controlStruct(:).Obj];
%             textList = {controlStruct(:).Text};
%         
%             ctr=0;
%             for ctrl = {'uitree','uibutton','uilabel'}
%                 ctr=ctr+1;
%         
%                 % Find object(s)
%                 g = findobj(f,'Type',ctrl{:});
%         
%                 % If tree
%                 if ctr == 1
%                     % Get rows in struct that match control type (only one for tree)
%                     objRows = find(strcmp(objList,ctrl{:}));
%                     % Get ratio and base new position on size of figure window
%                     ratio = controlStruct(objRows).Ratio;
%                     newPos = f.Position.*ratio;
%                     g.Position = newPos;
%         
%                 else
%         
%                     % Loop through controls found in figure and match with those in
%                     % struct
%                     for iObj = 1:numel(g)
%         
%                         % Get control text to search for struct
%                         txt = g(iObj).Text;
%         
%                         % If it's empty, icon 
%                         if isempty(txt)
%                             txt = g(iObj).Icon;
%                         end
%         
%                         % Find rows matching object type
%                         objRows = find(strcmp(objList,ctrl{:}));
%                         % Find rows matching text
%                         txtRows = find(strcmp(txt,textList));
%                         % Index of shared member obj and textrows
%                         idx = find(ismember(objRows,txtRows));
%                         % Get ratio
%                         ratio = controlStruct(objRows(idx)).Ratio;
%                         % Get new position based on figure position
%                         newPos = f.Position.*ratio;
%                         g(iObj).Position = newPos;
%         
%         
%                     end
%                 end
%             end
        end    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            app.EyeTrackLibraryEditFigure.Visible = 'off';
            % Resize based on screen resolution
            app.changeAppPosition;
            
            % Change control positions based on new figure position
            app.changeControlPositions;

            % Initialize properties
            app.initializeProperties;
            
            % Load libraries
            loaded = app.loadLibraries;
            
            if loaded
                % Build task Tree
                app.createTaskTree;
                
            end
            
            % Set app to visible
            app.EyeTrackLibraryEditFigure.Visible = 'on';            
        end

        % Selection changed function: TreeTasks
        function TreeTasksSelectionChanged(app, event)
            selectedNodes = app.TreeTasks.SelectedNodes;
            
            userdata = selectedNodes.UserData;
            
            if isa(userdata,'EyeTrackImage') == 1

                try
                    % Set Image property
                    app.Image = userdata;
                    
                    % Display image in thumbnail
                    app.loadImageToThumbnail(userdata);
                    
                catch 
                    msgbox("Image can't be loaded")
                end
            elseif isa(userdata,'EyeTrackTask') == 1
                app.Task = userdata;
            elseif isa(userdata,'EyeTrackAOI')
                % Reload parent Image
                app.Image = selectedNodes.Parent.UserData;
                app.loadImageToThumbnail(app.Image);
                % Draw AOI
                app.NewAoi = userdata;
                drawNewAoiPatches(app,app.axThumbnail);
                
            end            
        end

        % Button pushed function: btnEditLibItem
        function btnEditLibItemButtonPushed(app, event)
            
            % Make tree editable
            app.TreeTasks.Editable = 'on';
        end

        % Node text changed function: TreeTasks
        function TreeTasksNodeTextChanged(app, event)
            node = event.Node;
            
            app.TreeTasks.Editable = 'off';
            
            % Update Tasks Object
            NewTasks = editLibItem(node,app.Tasks,'EDIT');
            app.Tasks = NewTasks;
        end

        % Button pushed function: btnDeleteLibItem
        function btnDeleteLibItemButtonPushed(app, event)
            
            % Get selected treenode            
            selectedNode = app.TreeTasks.SelectedNodes;
            
            % Check a tree node has been selected
            if ~isempty(selectedNode)
                
                % Update Tasks Object
                NewTasks = editLibItem(selectedNode,app.Tasks,'DELETE');
                app.Tasks = NewTasks;
                
                % Delete node from tree
                selectedNode.delete
            end
        end

        % Button pushed function: btnReloadLibrary
        function btnReloadLibraryButtonPushed(app, event)
            
            app.lblWait.Visible = 'on';
            pause(1);
            % Delete thumbnail image and tree
            app.axThumbnail.Children.delete;
            app.TreeTasks.Children.delete;
            
            % Load libraries
            loaded = app.loadLibraries;
            
            if loaded
                % Build task Tree
                app.createTaskTree;
                
            end
            app.lblWait.Text = 'Done!';
            pause(5);
            app.lblWait.Visible='off';
            app.lblWait.Text = 'Please wait...';

            
        end

        % Button pushed function: btnAoiUp
        function btnAoiUpButtonPushed(app, event)
            
            % Get selected treenode            
            selectedNode = app.TreeTasks.SelectedNodes;
            
            % Check is an Aoi object
            if ~isempty(selectedNode)
                userData = selectedNode.UserData;
                
                % If is an AOI Object, move up if possible
                if isa(userData,'EyeTrackAOI')
                    
                    % Get 2 nodes to be moved
                    [n1,n2] = moveTreeNodeUp(selectedNode);
                    
                    % Get New Tasks Object based on new AOI positions
                    NewTasks = moveLibAois(n1,n2,app.Tasks);
                    app.Tasks = NewTasks;
                end
            end
            
        end

        % Button pushed function: btnAoiDown
        function btnAoiDownButtonPushed(app, event)
            
            % Get selected treenode            
            selectedNode = app.TreeTasks.SelectedNodes;
            
            % Check is an Aoi object
            if ~isempty(selectedNode)
                userData = selectedNode.UserData;
                
                % If is an AOI Object, move up if possible
                if isa(userData,'EyeTrackAOI')
                    
                    % Get 2 nodes to be moved
                    [n1,n2] = moveTreeNodeDown(selectedNode);
                    
                    % Get New Tasks Object based on new AOI positions
                    NewTasks = moveLibAois(n1,n2,app.Tasks);
                    app.Tasks = NewTasks;
                end
            end
            
        end

        % Button pushed function: btnSaveLibrary
        function btnSaveLibraryButtonPushed(app, event)
            
            app.saveTasksLibrary;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EyeTrackLibraryEditFigure = uifigure;
            app.EyeTrackLibraryEditFigure.Name = 'Edit Tasks Library';
            app.TreeTasks = uitree(app.EyeTrackLibraryEditFigure);
            app.TreeTasks.SelectionChangedFcn = createCallbackFcn(app, @TreeTasksSelectionChanged, true);
            app.TreeTasks.NodeTextChangedFcn = createCallbackFcn(app, @TreeTasksNodeTextChanged, true);
            app.lblTaskTree = uilabel(app.EyeTrackLibraryEditFigure);
            app.lblTaskTree.Text = 'List of Tasks and Associated Images';
            app.axThumbnail = uiaxes(app.EyeTrackLibraryEditFigure);
            app.axThumbnail.XTick = [];
            app.axThumbnail.YTick = [];
            app.btnEditLibItem = uibutton(app.EyeTrackLibraryEditFigure, 'push');
            app.btnEditLibItem.ButtonPushedFcn = createCallbackFcn(app, @btnEditLibItemButtonPushed, true);
            app.btnEditLibItem.Text = 'Edit Names';
            app.btnDeleteLibItem = uibutton(app.EyeTrackLibraryEditFigure, 'push');
            app.btnDeleteLibItem.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteLibItemButtonPushed, true);
            app.btnDeleteLibItem.Text = 'Delete';
            app.btnSaveLibrary = uibutton(app.EyeTrackLibraryEditFigure, 'push');
            app.btnSaveLibrary.ButtonPushedFcn = createCallbackFcn(app, @btnSaveLibraryButtonPushed, true);
            app.btnSaveLibrary.Text = 'Save';
            app.btnReloadLibrary = uibutton(app.EyeTrackLibraryEditFigure, 'push');
            app.btnReloadLibrary.ButtonPushedFcn = createCallbackFcn(app, @btnReloadLibraryButtonPushed, true);
            app.btnReloadLibrary.Text = 'Reload Lib';
            app.btnAoiUp = uibutton(app.EyeTrackLibraryEditFigure, 'push');
            app.btnAoiUp.ButtonPushedFcn = createCallbackFcn(app, @btnAoiUpButtonPushed, true);
            app.btnAoiUp.Icon = 'aoiUp.png';
            app.btnAoiUp.Text = '';
            app.btnAoiDown = uibutton(app.EyeTrackLibraryEditFigure, 'push');
            app.btnAoiDown.ButtonPushedFcn = createCallbackFcn(app, @btnAoiDownButtonPushed, true);
            app.btnAoiDown.Icon = 'aoiDown.png';
            app.btnAoiDown.Text = '';
            app.AOILabel = uilabel(app.EyeTrackLibraryEditFigure);
            app.AOILabel.Text = 'AOI';
            app.PositionLabel = uilabel(app.EyeTrackLibraryEditFigure);
            app.PositionLabel.Text = 'Position';
            app.lblWait = uilabel(app.EyeTrackLibraryEditFigure);
            app.lblWait.Visible = 'off';
            app.lblWait.Text = 'Please wait...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EyeTrackLibraryEdit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EyeTrackLibraryEditFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EyeTrackLibraryEditFigure)
        end
    end
end