classdef EyeTrackRunTask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EyeTrackRunTaskUIFigure        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ParseDataTab                   matlab.ui.container.Tab
        GapFillPanel                   matlab.ui.container.Panel
        cbxGapFill                     matlab.ui.control.CheckBox
        MaxGapLengthEditFieldLabel     matlab.ui.control.Label
        tbMaxGap                       matlab.ui.control.NumericEditField
        msLabel                        matlab.ui.control.Label
        grpEyeSelection                matlab.ui.container.ButtonGroup
        btnLeft                        matlab.ui.control.ToggleButton
        btnAverage                     matlab.ui.control.ToggleButton
        btnStrictAverage               matlab.ui.control.ToggleButton
        btnRight                       matlab.ui.control.ToggleButton
        VelocityPanel                  matlab.ui.container.Panel
        WindowLengthLabel              matlab.ui.control.Label
        tbWindowLength                 matlab.ui.control.NumericEditField
        msLabel_2                      matlab.ui.control.Label
        FixationclassifierPanel        matlab.ui.container.Panel
        VelocityThresholdLabel         matlab.ui.control.Label
        tbVelocity                     matlab.ui.control.NumericEditField
        degsLabel                      matlab.ui.control.Label
        MergePanel                     matlab.ui.container.Panel
        cbxMerge                       matlab.ui.control.CheckBox
        MaxtimebetweenfixationsLabel   matlab.ui.control.Label
        tbMaxMergeTime                 matlab.ui.control.NumericEditField
        msLabel_3                      matlab.ui.control.Label
        MaxanglebetweenfixationsLabel  matlab.ui.control.Label
        tbMaxMergeAngle                matlab.ui.control.NumericEditField
        degLabel                       matlab.ui.control.Label
        ddMergeOrder                   matlab.ui.control.DropDown
        DiscardPanel                   matlab.ui.container.Panel
        cbxDiscard                     matlab.ui.control.CheckBox
        MinimumfixationdurationLabel   matlab.ui.control.Label
        tbMinFixationTime              matlab.ui.control.NumericEditField
        msLabel_4                      matlab.ui.control.Label
        ddDiscardOrder                 matlab.ui.control.DropDown
        ParseDataintoFixationsLabel    matlab.ui.control.Label
        ddParseYesNo                   matlab.ui.control.DropDown
        btnNext1                       matlab.ui.control.Button
        btnResetDefault                matlab.ui.control.Button
        SelectTaskTab                  matlab.ui.container.Tab
        ddChooseTask                   matlab.ui.control.DropDown
        lblChooseTask                  matlab.ui.control.Label
        lblSelectImages                matlab.ui.control.Label
        lbxImages                      matlab.ui.control.ListBox
        cbxSelectAllImages             matlab.ui.control.CheckBox
        btnAddImage                    matlab.ui.control.Button
        lbxSelectedImages              matlab.ui.control.ListBox
        lblSelectedImages              matlab.ui.control.Label
        lbxFinalImagesAois             matlab.ui.control.ListBox
        btnRemoveImage                 matlab.ui.control.Button
        lblFinalImagesAois             matlab.ui.control.Label
        lblFinalImagesAois_2           matlab.ui.control.Label
        cbxSelectAllAois               matlab.ui.control.CheckBox
        lbxAois                        matlab.ui.control.ListBox
        btnAddAois                     matlab.ui.control.Button
        btnRemoveAois                  matlab.ui.control.Button
        btnBack2                       matlab.ui.control.Button
        btnNext2                       matlab.ui.control.Button
        cbxSelectAllAddedImages        matlab.ui.control.CheckBox
        cbxSelectAllAddedAois          matlab.ui.control.CheckBox
        TemporalFilterTab              matlab.ui.container.Tab
        btnBack3                       matlab.ui.control.Button
        btnNext3                       matlab.ui.control.Button
        ddSelectAnchor                 matlab.ui.control.DropDown
        lblSelectAnchor                matlab.ui.control.Label
        WindowStartPanel               matlab.ui.container.Panel
        sldWindowStart                 matlab.ui.control.Slider
        txtWindowStart                 matlab.ui.control.NumericEditField
        lblSelectWindowStart           matlab.ui.control.Label
        WindowEndPanel                 matlab.ui.container.Panel
        lblSelectWindowEnd             matlab.ui.control.Label
        sldWindowEnd                   matlab.ui.control.Slider
        txtWindowEnd                   matlab.ui.control.NumericEditField
        DataTab                        matlab.ui.container.Tab
        LocateStudyFolderLabel         matlab.ui.control.Label
        txtSubjectPath                 matlab.ui.control.EditField
        btnGetSubjectPath              matlab.ui.control.Button
        btnBack4                       matlab.ui.control.Button
        btnNext4                       matlab.ui.control.Button
        cbxTotalFixationDuration       matlab.ui.control.CheckBox
        cbxTimeToFirstFixate           matlab.ui.control.CheckBox
        tblSubjects                    matlab.ui.control.Table
        SelectsubjectstoincludeLabel   matlab.ui.control.Label
        cbxAllSubjects                 matlab.ui.control.CheckBox
        cbxTotalLookingTime            matlab.ui.control.CheckBox
        cbxRawFilter                   matlab.ui.control.CheckBox
        cbxTotalFixationCount          matlab.ui.control.CheckBox
        ReviewTab                      matlab.ui.container.Tab
        tblReview                      matlab.ui.control.Table
        btnBack5                       matlab.ui.control.Button
        btnRun                         matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        parsePanels % Description
    end
    
    properties (Access = public)
        Filter EyeTrackFixationFilter % Description
        Tasks EyeTrackTasks
        Task EyeTrackTask
        RunTask EyeTrackTask
        Image EyeTrackImage
        Aoi EyeTrackAOI
        MonitorDisplay
        windowStartTime
        windowEndTime
        studyPath
        subjectIds
        subjectPaths
        aoiList
        aoiDeleteIdx % Index of aois selected for processing
    end
    
    methods (Access = private)
        
        function changeAppPosition(app)
%               50,40,450,640
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*(50/1280);
            bottom = screenHeight*(40/720);
            width = screenWidth*(450/1280);
            height = screenHeight*(640/720);
            drawnow;
            app.EyeTrackRunTaskUIFigure.Position = [left bottom width height]; 
        end
        
        function success = loadLibraries(app)
            
            % Get location of text file containing storage location of libraries
            txtDir = fileparts(app.getcurrentdir);
            
            % Get directory from text file
            % location text file
            locText = fullfile(txtDir,'LibLocation.txt');
%             fileId = fopen(locText);
            
%             % If fileId = -1 file not found
%             if fileId == -1
%                 msgbox('Locations text file cannot be found');
%                 success = 0;
%                 return
%             else
% %                 libLoc = fscanf(fileId,'%s');
%             end

            try
                libLoc = fileread(locText);
                
                % Load Tasks Library
                TaskLib = load(fullfile(libLoc,'EyeTrackTasksLib.mat'));
                app.Tasks = TaskLib.EyeTrackTasksLib;
                
                % Load monitors struct
                MonitorLib = load(fullfile(libLoc,'monitors.mat'));
                app.MonitorDisplay = MonitorLib.monitors;
                
            catch ME
                ME;
                msgbox(ME.message);
                success = 0;
                return
            end
            
            success = 1;


        end
        
        function populateImages(app)
             
            % Task Names to drop down
            app.ddChooseTask.Items = {app.Tasks.Items.taskName};
            
            % Image IDs from first Task returned
            app.Task = app.Tasks.Items(1);
            app.RunTask = app.Task;
            
            % Display image IDs in listbox
            app.lbxImages.Items = {app.Task.Images.imageID};
            
        end
        
        function addItemsToListBox(app,lbxFrom,lbxTo)
            

            % Items Data
            lbxFrom.ItemsData = [];
            % Get index of selected items
            [~, selectedItems] = ismember(lbxFrom.Value, lbxFrom.Items);

            % Items to add and existing items in listbox
            itemsToAdd = lbxFrom.Items(selectedItems);
            existingItems = lbxTo.Items;

            % If listbox is empty, selected items to new listbox
            % otherwise add to list
            emptyChk = app.isEmptyListbox(lbxTo);
            
            if emptyChk
                lbxTo.Items = itemsToAdd;
            else
                items = [existingItems,itemsToAdd];
                lbxTo.Items = unique(items);
            end 
            
         
        end
    
        function removeItemsFromListBox(app,lbx,updateAois)
            
            % number of Items
            nItems = length(lbx.Items);
            
            % Number of selected Items;
            nSelected = length(lbx.Value);
            
            % If all items selected - clear listbox
            if nItems == nSelected
                app.clearListboxes(lbx)
            else
            
                % Get index of selected items
                [~,selectedItems] = ismember(lbx.Value, lbx.Items);
                
                % Get number of items as array
                allIdx = [1:nItems];
                
                % Items not selected (those to replace existing items)
                notSelected = find(~ismember(allIdx,selectedItems));
                
                % New items to be added to list box
                newItems = lbx.Items(notSelected);
                
                % Add items to listbox
                lbx.Items = newItems;
                lbx.Value = {};
                
            end
            
            % If update Aois and not all items selected then
            % refresh AOI list
            % Otherwise clear all listboxes
            if updateAois && nItems > nSelected
                % Refresh Aoi List
                app.addAoiList;
                app.clearListboxes(app.lbxFinalImagesAois);
                
            elseif updateAois && nItems == nSelected
                app.clearListboxes(app.lbxAois)
                app.clearListboxes(app.lbxFinalImagesAois)
                
                % Deselect checkboxes
                app.cbxSelectAllAddedAois.Value = 0;
                app.cbxSelectAllAddedImages.Value = 0;
                app.cbxSelectAllAois.Value = 0;
                
            end
        end
        
        function addAoiList(app,idx)
            %% Update AOIs listbox
            
%             % All image items
%             allItems = [app.lbxImages.Items];
%             % Images added to list
%             addedItems = app.lbxSelectedImages.Items;
%             % Get image index
% %             idx = cellfun(@(x) find(contains(allItems,x)),addedItems,'UniformOutput',false);
%             idx = cellfun(@(x) find(strcmp(allItems, x)), addedItems,'UniformOutput',false);
%             idx = [idx{:}];
%             allIdx = [1:length(app.lbxImages.Items)];
%             
%             % Create new task, subsetting on selected images
%             NewTask = app.Task;
% %             
%             % Delete images not seleceted
%             notSelected =find(~ismember(allIdx,idx));
%             NewTask.Images(notSelected) = [];
%             
            % Create list of AOI names
%             aoiList = aoiTableHeaders(NewTask);
%             aoiList = aoiTableHeaders(app.RunTask);
%             aoiList = strrep(aoiList,'_TFD','');
            % Display AOI names in listbox
%             app.lbxAois.Items = aoiList;
            
        end
        
        function clearListboxes(app,lbx)
            
            % lbx input as listbox item
            % Clear selected images
            nItems = length(lbx.Items);
            newItems = cell(1,nItems);
            newItems{1} = {' '};
            lbx.Items = [newItems{:}];
            lbx.Value = {};
            
            
        end
        
        function taskChangeCallback(app,src,event)
            display(event.SelectedOption);
        end
        
        function out = isEmptyListbox(app,lbx)
            
            % Check listbox items
            nItems = length(lbx.Items);
            item1 = lbx.Items{1};
            
            % If item 1 = {' '} and only one item --> empty
            
            if nItems == 1 && strcmp(item1,{' '})
                out = 1;
            else
                out = 0;
            end            
            
            
        end
        
        function selectAllListBoxItems(app,lbx)
            
            % Select all items in Listbox object
            nItems = length(lbx.Items);
            lbx.ItemsData = 1:nItems;
            lbx.Value = lbx.ItemsData;
            lbx.ItemsData = [];
        end
        
        function createReviewTable(app)
            
            % Get fixation filter settings
            fixationCols = app.createFixationCols;
            
            % Get task details
            taskCols = app.createTaskCols;
            
            % Get temporal filter settings
            temporalCols = app.createTemporalCols;
            
            combinedData = [fixationCols;taskCols;temporalCols];
            % Create table
%             tdata = table(...
%                 fixationCols(:,1),fixationCols(:,2),fixationCols(:,3));
            tdata = table(combinedData(:,1),combinedData(:,2),combinedData(:,3));
            app.tblReview.Data = tdata;


%             
%             if nSubj > 1                
%                 chks = true(nSubj,1);
%                 tdata = table(chks,app.subjects);
%                 app.tblReview.ColumnEditable = [true,false];
%                 app.tblReview.ForegroundColor = [0 0 0];
%                 app.tblReview.FontAngle = 'normal';
%                 
%             else
%                 tdata = [{'N/A'} {'N/A'}];
%                 app.tblReview.ColumnEditable = [false,false];
%                 app.tblReview.ForegroundColor = [0.65 0.65 0.65];
%                 app.tblReview.FontAngle = 'italic';
%             end
%             app.tblReview.Data = tdata;            
        end
        
        function out = createFixationCols(app)
            
            if ~app.Filter.parseOn
                col1 = {'FIXATION FILTER';''};
                col2 = {'';'N/A'};
                col3 = {'';'N/A'};
                out = [col1,col2,col3];
                return
            end
            
            % Merge before discard
            if app.Filter.mergeBeforeDiscard
                mergeFirst = 'Yes';
                discardFirst = 'No';
            else
                mergeFirst = 'No';
                discardFirst = 'Yes';
            end
            
            % Merge adjacent fixations
            if app.Filter.merge
                merge = 'Enabled';
            else
                merge = 'Disabled';
            end
            
            % Discard short fixations
            if app.Filter.discard
                discard = 'Enabled';
            else
                discard = 'Discard';
            end
            
            % Gap Fill in
            if app.Filter.gapFill
                gapFill = 'Enabled';
            else
                gapFill = 'Disabled';
            end
            
                        
            col3 = {...
                '',...
                gapFill,app.Filter.maxGapLength,'',...
                app.Filter.eyeSelection,'',...
                app.Filter.windowLength,app.Filter.velocityThreshold,''...
                merge,app.Filter.maxMergeTime,...
                app.Filter.maxMergeAngle,mergeFirst,'',...
                discard,app.Filter.minFixationLength,...
                discardFirst,'',...
                }';
            
            col2 = {...
                '',...
                'Gap Fill In','Maximum Gap Length','',...
                'Eye Selection','',...
                'Velocity Window Length','Velocity Threshold',''...
                'Merge Adjacent Fixations','Maximum Time Between',...
                'Maximum Angle Between','Merge First','',...
                'Discard Short Fixations','Minimum Fixation Duration',...
                'Discard First','',...
                }'; 
            
            [nRows,~] = size(col2);
            col1 = cell(nRows,1);
            
            col1{1} = 'FIXATION FILTER';
            
            out = [col1,col2,col3];
        end
        
        function out = createTaskCols(app)
            
            % Get image IDs for task
            imageIds = {app.RunTask.Images.imageID}';
            nImages = numel(imageIds);
            nRows = nImages+2;
            
            % Create empty cell arrays
            [col1,col2,col3] = deal(cell(nRows,1));
            
            % Column one has identifier
            col1{1} = strcat(upper(app.RunTask.taskName),' TASK');
            
            % Image IDs to column 2
            col2(2:end-1) = imageIds;
            col2(end) = {'Number of subjects'};
            
            % Get AOI Ids
            aoiNames = [app.RunTask.Images(1).Aois.aoiName];
            aoiNames = strcat(aoiNames,',');
            aoiNames = char(join(aoiNames));
            aoiNames = aoiNames(1:end-1);
            
            
            for iImg = 1:nImages
                col3(iImg+1) = {aoiNames};
            end
            
            % Add number of subjects
            col3(end) = {numel(app.subjectIds)};
            
            % Get AOI list
%             ImageIds = {app.RunTask.Images.imageID};

%             for iImg = 1:length(ImageIds)
%                 
%                 % Get image ID
%                 imageId = ImageIds{iImg};
%                 
%                 % Find image ID in aoiList
%                 idx = find(contains(app.aoiList,imageId));
%                 
%                 % Cell Array, split by '_'
%                 splitArr = cellfun(@(x) regexp(x,'\_','split'),app.aoiList(idx),'UniformOutput',false);
%                 
%                 % Cell containing Aois
%                 aoiCell = cellfun(@(x) x{1,end},splitArr,'UniformOutput',false);
                
                % Comma separated list
%                 csl = strcat(aoiCell,',');
%                 csl = [csl{:}];
                
                % Add to column
%                 col3(iImg+1) = {csl(1:end-1)};
                
%             end
            
            out = [col1,col2,col3];

        end
        
        function out = createTemporalCols(app)
            
            % Create empty columns
            [col1,col2,col3] = deal(cell(4,1));
            
            col1{1} = 'TEMPORAL FILTER';
            col2(2:4) = {...
                'Onset',...
                'Window Start',...
                'Window End'};
            col3(2:4) = {...
                app.ddSelectAnchor.Value,...
                strcat(num2str(app.txtWindowStart.Value),' ms'),...
                strcat(num2str(app.txtWindowEnd.Value),' ms'),...
                };
            
            out = [col1,col2,col3];
            
        end
        
        function initializeProperties(app)
            
            app.Filter=EyeTrackFixationFilter;
            app.Tasks=EyeTrackTasks;
            app.Task=EyeTrackTask;
            app.RunTask=EyeTrackTask;
            app.Image=EyeTrackImage;
            app.Aoi=EyeTrackAOI;
            
        end
        
        function updateRunTask(app,lbxList,lbxSelect,aoiImage)
            
            % Get Aois/images not in list
            deleteIdx=find(~ismember(lbxList.Items,lbxSelect.Items));

            if strcmpi(aoiImage,'AOI')
            
                % Loop through images and delete
                for iImg = 1:app.RunTask.Count
                    app.RunTask.Images(iImg).Aois(deleteIdx) = [];
                end
                
            else
                
                % New Run Task
                app.RunTask = app.Task;
    
                % delete images from task
                app.RunTask.Images(deleteIdx) = [];
                
            end
            
        end
        
        function updateSubjects(app)
            
            % Get subject table data
            dat = app.tblSubjects.Data;
            
            % Get all ids and paths
            [ids,paths] = getSubjectIDs(app.txtSubjectPath.Value);

            % Get selected subjects
            app.subjectIds = dat{find(dat{:,1}),2};
        
            % Get subject file paths
            app.subjectPaths = paths(find(dat{:,1}));
            
            
        end
    end


    methods (Access = public)
       function currentDir = getcurrentdir(app)
           
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end     
       end

    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EyeTrackRunTaskUIFigure.Visible = 'off';
            % Resize based on screen resolution
            app.changeAppPosition;

            % Get graphics array for parsePanels (hide/show)
            p = findobj(app.ParseDataTab.Children,'Type','uipanel');
            g = findobj(app.ParseDataTab.Children,'Type','uibuttongroup');
            app.parsePanels = [p;g];
            
            % Initialise properties
            app.initializeProperties;
            
            % Fixation Filter panels invisible
            set(app.parsePanels,'Visible',lower(app.ddParseYesNo.Value));
            app.Filter.parseOn = 0;
            % After resizing - make app visible
            app.EyeTrackRunTaskUIFigure.Visible = 'on';
            
            % Load task and monitors library
            loaded = app.loadLibraries;   
            if loaded
                app.populateImages;
            end


        end

        % Value changed function: ddParseYesNo
        function ddParseYesNoValueChanged(app, event)
            value = app.ddParseYesNo.Value;
            
            % Set panels visibility based on on/off
            % Parse invisible
            set(app.parsePanels,'Visible',lower(value));
            
            tfd = app.cbxTotalFixationDuration;
            ttff = app.cbxTimeToFirstFixate;
            raw = app.cbxRawFilter;
            tlt = app.cbxTotalLookingTime;
                
                if strcmpi(value,'On')
                   set([tfd,ttff],'Value',1,'Enable','on');
                   set(raw,'Value',0,'Enable','on');
                   set(tlt,'Value',0,'Enable','off');
                else
                   set([tfd,ttff,raw],'Value',0,'Enable','off');
                   set(tlt,'Value',1,'Enable','on');
                end


            app.Filter.parseOn = value;
            
        end

        % Value changed function: ddMergeOrder
        function ddMergeOrderValueChanged(app, event)
            value = app.ddMergeOrder.Value;
            value = str2double(value);
            
            % If value == 1 then merge first
            if value == 1
                app.ddDiscardOrder.Value = '2';
                app.Filter.mergeBeforeDiscard = 1;
                
            elseif value == 2
                app.ddDiscardOrder.Value = '1';
                app.Filter.mergeBeforeDiscard = 0;
            end
        end

        % Value changed function: ddDiscardOrder
        function ddDiscardOrderValueChanged(app, event)
            value = app.ddDiscardOrder.Value;
            value = str2double(value);
            
            % If value == 1 then merge last
            if value == 1
                app.ddMergeOrder.Value = '2';
                app.Filter.mergeBeforeDiscard = 0;
            else
                app.ddMergeOrder.Value = '1';
                app.Filter.mergeBeforeDiscard = 1;
            end
            
        end

        % Value changed function: tbMaxGap
        function tbMaxGapValueChanged(app, event)
            value = app.tbMaxGap.Value;
            
            % Maximum gap length for Gap Fill-in
            app.Filter.maxGapLength = value;
            
        end

        % Button pushed function: btnNext1
        function btnNext1Pushed(app, event)
            
            % Load task and monitors library
            app.loadLibraries;   
            app.populateImages;
            
            % Change tab
            app.TabGroup.SelectedTab = app.SelectTaskTab;
        end

        % Value changed function: cbxGapFill
        function cbxGapFillValueChanged(app, event)
            value = app.cbxGapFill.Value;
            
            % fill in gaps 0/1 true/false
            app.Filter.gapFill=value;
            
            % If enabled - enable gap length
            if value == 1
                app.tbMaxGap.Enable = 'on';
            else
                app.tbMaxGap.Enable = 'off';
            end
        end

        % Selection changed function: grpEyeSelection
        function grpEyeSelectionSelectionChanged(app, event)
            selectedButton = app.grpEyeSelection.SelectedObject;
            
            % Eye selection property of fixation filter
            app.Filter.eyeSelection = selectedButton.Text;
            
        end

        % Value changed function: tbWindowLength
        function tbWindowLengthValueChanged(app, event)
            value = app.tbWindowLength.Value;
            
            % Fixation Filter window length
            app.Filter.windowLength = value;
        end

        % Value changed function: tbVelocity
        function tbVelocityValueChanged(app, event)
            value = app.tbVelocity.Value;
            
            % Fixation Filter velocity threshold
            app.Filter.velocityThreshold = value;
        end

        % Value changed function: cbxMerge
        function cbxMergeValueChanged(app, event)
            value = app.cbxMerge.Value;
            
            % Fixation Filter merge on/off
            app.Filter.merge = value;
            
            % Enable/Disable merge panel controls
            if value == 1
                app.tbMaxMergeTime.Enable = 'on';
                app.tbMaxMergeAngle.Enable = 'on';
                app.ddMergeOrder.Enable = 'on'; 
            else
                app.tbMaxMergeTime.Enable = 'off';
                app.tbMaxMergeAngle.Enable = 'off';
                app.ddMergeOrder.Enable = 'off';
            end
            
            % Get discard value
            % If merge has been enabled but discard is not enabled
            % Must set merge order to 1
            dValue = app.cbxDiscard.Value;
            if value == 1 && dValue == 0
                app.ddMergeOrder.Value = '1';
                app.ddDiscardOrder.Value = '2';
            elseif value == 0 && dValue == 1
                app.ddMergeOrder.Value = '2';
                app.ddDiscardOrder.Value = '1';
            end
                

        end

        % Value changed function: tbMaxMergeTime
        function tbMaxMergeTimeValueChanged(app, event)
            value = app.tbMaxMergeTime.Value;
            
            % Fixation Filter maximum time to merge adjacent fixations
            app.Filter.maxMergeTime = value;
        end

        % Value changed function: tbMaxMergeAngle
        function tbMaxMergeAngleValueChanged(app, event)
            value = app.tbMaxMergeAngle.Value;

            % Fixation Filter maximum angle to merge adjacent fixations
            app.Filter.maxMergeAngle = value;
            
        end

        % Value changed function: cbxDiscard
        function cbxDiscardValueChanged(app, event)
            value = app.cbxDiscard.Value;
            
            % Fixation Filter discard short fixations on/off
            app.Filter.discard = value;
            
            % Enable/Disable discard panel controls
            if value == 1
                app.tbMinFixationTime.Enable = 'on';
                app.ddDiscardOrder.Enable = 'on';
            else
                app.tbMinFixationTime.Enable = 'off';
                app.ddDiscardOrder.Enable = 'off';
            end    
            
            % Get merge value
            % If discard has been enabled but merge is not enabled
            % Must set discard order to 1
            mValue = app.cbxMerge.Value;
            if value == 1 && mValue == 0
                app.ddDiscardOrder.Value = '1';
                app.ddMergeOrder.Value = '2';
            elseif value == 0 && mValue == 1
                app.ddDiscardOrder.Value = '2';
                app.ddMergeOrder.Value = '1';
            end
            
        end

        % Value changed function: tbMinFixationTime
        function tbMinFixationTimeValueChanged(app, event)
            value = app.tbMinFixationTime.Value;
            
            % Fixation Filter minimum fixation length
            app.Filter.minFixationLength = value;
        end

        % Value changed function: ddChooseTask
        function ddChooseTaskValueChanged(app, event)
            value = app.ddChooseTask.Value;
            
            % Default response
            response = 'OK';
            
            % Check selected images listbox is empty
            lbx = app.lbxSelectedImages;
            emptyLbx = app.isEmptyListbox(lbx);
            
            if emptyLbx == 0
            
                % Confirm user wishes to change task
                response = uiconfirm(app.EyeTrackRunTaskUIFigure,...
                    'Change task? Doing so will reset form',...
                    'Confirm Change Task','CloseFcn',@(src,event)taskChangeCallback(app,src,event));             
            end
            
            % get listbox from and to
            lbxFrom = app.lbxImages;
            lbxTo = app.lbxSelectedImages;
            
            if strcmpi(response,'OK') && emptyLbx == 0
                % Assign Task object from selected name
                app.ddChooseTask.ItemsData = 1:app.Tasks.Count;
                taskIdx = app.ddChooseTask.Value;
                app.Task = app.Tasks.Items(taskIdx);
                app.RunTask = app.Task;
                app.ddChooseTask.ItemsData = [];
                
                % Clear listboxes
                app.clearListboxes(app.lbxSelectedImages);
                app.clearListboxes(app.lbxAois);
                app.clearListboxes(app.lbxFinalImagesAois);
                app.clearListboxes(app.lbxImages);
            
                % Add image IDs to listbox
                app.lbxImages.ItemsData = [];
                app.lbxImages.Items = {app.Task.Images.imageID};
                
            elseif strcmpi(response,'OK') && emptyLbx == 1

                % Assign Task object from selected name
                app.ddChooseTask.ItemsData = 1:app.Tasks.Count;
                taskIdx = app.ddChooseTask.Value;
                app.Task = app.Tasks.Items(taskIdx);
                app.RunTask = app.Task;
                app.ddChooseTask.ItemsData = [];
                
                
                % Add image IDs to listbox
%                 app.addItemsToListBox(lbxFrom,lbxTo);
                app.lbxImages.ItemsData = [];
                app.lbxImages.Items = {app.Task.Images.imageID};
                
            elseif strcmpi(response,'CANCEL')
                app.ddChooseTask.Value = event.PreviousValue;
            end
            
            set([app.cbxSelectAllAois,app.cbxSelectAllImages],...
                'Value',0);
            
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if strcmp(selectedTab.Title,'Select Task')
                app.loadLibraries;
                
                app.populateImages;
                
            elseif strcmpi(selectedTab.Title,'Review')  
                
                % Update subjects
                app.updateSubjects;
                
                % Create Table
                app.createReviewTable;
                
            elseif strcmpi(selectedTab.Title,'Data')
                
%                 tfd = app.cbxTotalFixationDuration;
%                 ttff = app.cbxTimeToFirstFixate;
%                 tlt = app.cbxTotalLookingTime;
%                 
%                 if app.Filter.parseOn
%                    set([tfd,ttff],'Value',1,'Enable','on');
%                    set(tlt,'Value',0,'Enable','off');
%                    
%                 else
%                    set([tfd,ttff],'Value',0,'Enable','off');
%                    set(tlt,'Value',1,'Enable','on');
%                 end
            end
        end

        % Button pushed function: btnAddImage
        function btnAddImageButtonPushed(app, event)
            
%             app.RunTask = app.Task;
            
            % Listboxes
            lbxFrom = app.lbxImages;
            lbxTo = app.lbxSelectedImages;
            
            % Add images to listbox
            app.addItemsToListBox(lbxFrom,lbxTo);
            
            % Get image index value
            nImgs = length(lbxFrom.Items);
            lbxFrom.ItemsData = 1:nImgs;
            idx = lbxFrom.Value;
            
            % Update Run Task
            app.updateRunTask(lbxFrom,lbxTo,'IMAGE');
            
%             deleteIdx = find(~ismember([1:nImgs],idx));
%             app.RunTask.Images(deleteIdx) = [];
            
            % Add AOI Names to listbox
            app.lbxAois.Items = [app.RunTask.Images(1).Aois.aoiName];

        end

        % Value changed function: lbxImages
        function lbxImagesValueChanged(app, event)
            value = app.lbxImages.Value;
            
        end

        % Value changed function: cbxSelectAllImages
        function cbxSelectAllImagesValueChanged(app, event)
            value = app.cbxSelectAllImages.Value;
            
            lbx = app.lbxImages;

            if value == 1
                % Select all listbox items
                app.selectAllListBoxItems(lbx)
            else
                lbx.Value = {};
            end
        end

        % Value changed function: cbxSelectAllAois
        function cbxSelectAllAoisValueChanged(app, event)
            value = app.cbxSelectAllAois.Value;
            
            % Select all listbox items
            lbx = app.lbxAois;

            if value == 1
                % Select all listbox items
                app.selectAllListBoxItems(lbx)
            else
                lbx.Value = {};
            end            
        end

        % Button pushed function: btnAddAois
        function btnAddAoisButtonPushed(app, event)
            
            % listbox from/to
            lbxFrom = app.lbxAois;
            lbxTo = app.lbxFinalImagesAois;
            
            % Add AOI items to final listbox
            app.addItemsToListBox(lbxFrom,lbxTo);
            app.cbxSelectAllAddedAois.Value = 0;
            
            % Get Aois not in list
%             app.aoiDeleteIdx=find(~ismember(lbxFrom.Items,lbxTo.Items));

            % Update Run Task
            app.updateRunTask(lbxFrom,lbxTo,'AOI');
            
            % Refresh aoi List            
%             app.lbxFinalImagesAois.ItemsData = [];
%             app.aoiList = app.lbxFinalImagesAois.Items;
        end

        % Button pushed function: btnRemoveImage
        function btnRemoveImageButtonPushed(app, event)
            
            lbx = app.lbxSelectedImages;
            % Remove items and update Aoi List
            app.removeItemsFromListBox(lbx,1);
            
            % Update task
            app.updateRunTask(app.lbxImages,lbx,'IMAGE');
        end

        % Button pushed function: btnRemoveAois
        function btnRemoveAoisButtonPushed(app, event)
            
            lbxFrom = app.lbxFinalImagesAois;
            lbxTo = app.lbxAois;
            
            % Remove items, dont update Aois
            app.removeItemsFromListBox(lbxFrom,0);  
            app.cbxSelectAllAddedAois.Value = 0;
            
            % Get Aois not in list
%             app.aoiDeleteIdx=find(~ismember(lbxTo.Items,lbxFrom.Items));

            % Update Run Task
            app.updateRunTask(lbxTo,lbxFrom,'AOI');
            
            % Refresh aoi List
%             app.lbxFinalImagesAois.ItemsData = [];
%             app.aoiList = app.lbxFinalImagesAois.Items;
        end

        % Value changed function: cbxSelectAllAddedImages
        function cbxSelectAllAddedImagesValueChanged(app, event)
            value = app.cbxSelectAllAddedImages.Value;

            % Select all listbox items
            lbx = app.lbxSelectedImages;

            if value == 1
                % Select all listbox items
                app.selectAllListBoxItems(lbx)
            else
                lbx.Value = {};
            end              
        end

        % Value changed function: cbxSelectAllAddedAois
        function cbxSelectAllAddedAoisValueChanged(app, event)
            value = app.cbxSelectAllAddedAois.Value;
            
            % Select all listbox items
            lbx = app.lbxFinalImagesAois;

            if value == 1
                % Select all listbox items
                app.selectAllListBoxItems(lbx)
            else
                lbx.Value = {};
            end              
        end

        % Button pushed function: btnBack2
        function btnBack2ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParseDataTab;
        end

        % Button pushed function: btnNext2
        function btnNext2ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TemporalFilterTab;
        end

        % Button pushed function: btnGetSubjectPath
        function btnGetSubjectPathPushed(app, event)

            app.EyeTrackRunTaskUIFigure.Visible = 'off';
            % Get path of study file
            path = uigetdir;
            % Show text in box
            if path ~= 0
                app.txtSubjectPath.Value = path;
                app.studyPath = path;
                
                % Get list of subjects and their paths
                [app.subjectIds,app.subjectPaths] =...
                    updateSubjectsTable(app);
                app.EyeTrackRunTaskUIFigure.Visible = 'on';                
            else
                app.EyeTrackRunTaskUIFigure.Visible = 'on';                
                return
            end
            

        end

        % Button pushed function: btnRun
        function btnRunButtonPushed(app, event)
            
            % Create EyeTrackData Object based on app parameters
            SubjectData = createSubjectData(app);
            
            % Run processing function
            try
            
                % Output parameter table
                subjFolder = fileparts(app.subjectPaths{1});
                tableName = strcat(app.RunTask.taskName,'_parameterTable.CSV');
                saveName = fullfile(subjFolder,tableName);
                
                % Get table data
                t = app.tblReview.Data;
                t.Properties.VariableNames = {'GROUP','PARAMETER_IMAGE','SETTING_AOIs'};
                
                writetable(t,saveName);
                
                % Run process data
                eyeTrackProcessData(SubjectData,app.subjectIds,app.subjectPaths);
                
            catch ME
                app.TextArea.Value = getReport(ME,'extended');
            end
            
        end

        % Value changing function: sldWindowStart
        function sldWindowStartValueChanging(app, event)
            changingValue = event.Value;
            
            app.txtWindowStart.Value = changingValue;
        end

        % Value changed function: txtWindowStart
        function txtWindowStartValueChanged(app, event)
            value = app.txtWindowStart.Value;
            
            app.sldWindowStart.Value = value;
        end

        % Value changing function: sldWindowEnd
        function sldWindowEndValueChanging(app, event)
            changingValue = event.Value;
            
            app.txtWindowEnd.Value = changingValue;
        end

        % Button pushed function: btnBack3
        function btnBack3ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SelectTaskTab;
        end

        % Button pushed function: btnNext3
        function btnNext3ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DataTab;
        end

        % Button pushed function: btnBack4
        function btnBack4ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TemporalFilterTab;
        end

        % Button pushed function: btnNext4
        function btnNext4ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ReviewTab;
            
            % Update subjects
            app.updateSubjects;
            
            % Create Parameters Table
            app.createReviewTable;
        end

        % Button pushed function: btnBack5
        function btnBack5ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DataTab;
        end

        % Value changed function: lbxFinalImagesAois
        function lbxFinalImagesAoisValueChanged(app, event)
            value = app.lbxFinalImagesAois.Value;
            
            app.aoiList = app.lbxFinalImagesAois.Items;
        end

        % Value changed function: txtWindowEnd
        function txtWindowEndValueChanged(app, event)
            value = app.txtWindowEnd.Value;
            
            app.sldWindowEnd.Value = value;
        end

        % Button pushed function: btnResetDefault
        function btnResetDefaultButtonPushed(app, event)
            % Default Fixation Filter settings
            app.tbMaxGap.Value = 75;
            app.btnAverage.Value = 1;
            app.tbWindowLength.Value = 20;
            app.tbVelocity.Value = 30;
            app.tbMaxMergeTime.Value = 75;
            app.tbMaxMergeAngle.Value = 0.5;
            app.tbMinFixationTime.Value = 60;
            
            if ~app.cbxGapFill.Value
                app.cbxGapFill.Value = 1;
            end
            
            if ~app.cbxMerge.Value
                app.cbxMerge.Value = 1;
            end
            
            if ~app.cbxDiscard.Value
                app.cbxDiscard.Value = 1;
            end
        end

        % Value changed function: ddSelectAnchor
        function ddSelectAnchorValueChanged(app, event)
            value = app.ddSelectAnchor.Value;
            
            app.sldWindowStart.Value = 0;
            app.sldWindowEnd.Value = 0;
            app.txtWindowStart.Value = 0;
            app.txtWindowEnd.Value = 0;
        end

        % Value changed function: sldWindowStart
        function sldWindowStartValueChanged(app, event)
            startValue = app.sldWindowStart.Value;
            endValue = app.sldWindowEnd.Value;
            
            app.ddSelectAnchor.ItemsData = [1 2];
            anchorVal = app.ddSelectAnchor.Value;
            
            % If stimulus onset - start value = 0 as cannot be before image
            if anchorVal == 1 && startValue < 0 
                app.sldWindowStart.Value = 0;
                app.txtWindowStart.Value = 0;
            end
            
            % Window end can't be before window start
            if endValue < startValue
                app.sldWindowEnd.Value = startValue;
                app.txtWindowEnd.Value = startValue;
            end
            
        end

        % Value changed function: cbxAllSubjects
        function cbxAllSubjectsValueChanged(app, event)
            value = app.cbxAllSubjects.Value;
            
            if value == 1
                app.tblSubjects.Data(:,1) = {true};
            else
                app.tblSubjects.Data(:,1) = {false};
            end
            
        end

        % Value changed function: sldWindowEnd
        function sldWindowEndValueChanged(app, event)
            endValue = app.sldWindowEnd.Value;
            startValue = app.sldWindowStart.Value;
            
            if endValue < startValue
                app.sldWindowEnd.Value = startValue;
                app.txtWindowEnd.Value = startValue;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EyeTrackRunTaskUIFigure = uifigure('Visible', 'off');
            app.EyeTrackRunTaskUIFigure.Name = 'EyeTrackRunTask';
            app.TabGroup = uitabgroup(app.EyeTrackRunTaskUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ParseDataTab = uitab(app.TabGroup);
            app.ParseDataTab.Title = 'Parse Data';
            app.GapFillPanel = uipanel(app.ParseDataTab);
            app.GapFillPanel.Title = 'Gap fill-in (interpolation)';
            app.cbxGapFill = uicheckbox(app.GapFillPanel);
            app.cbxGapFill.ValueChangedFcn = createCallbackFcn(app, @cbxGapFillValueChanged, true);
            app.cbxGapFill.Text = 'Enabled';
            app.cbxGapFill.Value = true;
            app.MaxGapLengthEditFieldLabel = uilabel(app.GapFillPanel);
            app.MaxGapLengthEditFieldLabel.Text = 'Max Gap Length:';
            app.tbMaxGap = uieditfield(app.GapFillPanel, 'numeric');
            app.tbMaxGap.ValueChangedFcn = createCallbackFcn(app, @tbMaxGapValueChanged, true);
            app.tbMaxGap.Value = 75;
            app.msLabel = uilabel(app.GapFillPanel);
            app.msLabel.Text = 'ms';
            app.grpEyeSelection = uibuttongroup(app.ParseDataTab);
            app.grpEyeSelection.SelectionChangedFcn = createCallbackFcn(app, @grpEyeSelectionSelectionChanged, true);
            app.grpEyeSelection.Title = 'Eye selection';
            app.btnLeft = uitogglebutton(app.grpEyeSelection);
            app.btnLeft.Text = 'Left';
            app.btnAverage = uitogglebutton(app.grpEyeSelection);
            app.btnAverage.Text = 'Average';
            app.btnAverage.Value = true;
            app.btnStrictAverage = uitogglebutton(app.grpEyeSelection);
            app.btnStrictAverage.Text = 'Strict Average';
            app.btnRight = uitogglebutton(app.grpEyeSelection);
            app.btnRight.Text = 'Right';
            app.VelocityPanel = uipanel(app.ParseDataTab);
            app.VelocityPanel.Title = 'Velocity calculator';
            app.WindowLengthLabel = uilabel(app.VelocityPanel);
            app.WindowLengthLabel.Text = 'Window Length:';
            app.tbWindowLength = uieditfield(app.VelocityPanel, 'numeric');
            app.tbWindowLength.ValueChangedFcn = createCallbackFcn(app, @tbWindowLengthValueChanged, true);
            app.tbWindowLength.Value = 20;
            app.msLabel_2 = uilabel(app.VelocityPanel);
            app.msLabel_2.Text = 'ms';
            app.FixationclassifierPanel = uipanel(app.ParseDataTab);
            app.FixationclassifierPanel.Title = 'Fixation classifier';
            app.VelocityThresholdLabel = uilabel(app.FixationclassifierPanel);
            app.VelocityThresholdLabel.Text = 'Velocity Threshold:';
            app.tbVelocity = uieditfield(app.FixationclassifierPanel, 'numeric');
            app.tbVelocity.ValueChangedFcn = createCallbackFcn(app, @tbVelocityValueChanged, true);
            app.tbVelocity.Value = 30;
            app.degsLabel = uilabel(app.FixationclassifierPanel);
            app.degsLabel.Text = 'deg/s';
            app.MergePanel = uipanel(app.ParseDataTab);
            app.MergePanel.Title = 'Merge adjacent fixations';
            app.cbxMerge = uicheckbox(app.MergePanel);
            app.cbxMerge.ValueChangedFcn = createCallbackFcn(app, @cbxMergeValueChanged, true);
            app.cbxMerge.Text = 'Enabled';
            app.cbxMerge.Value = true;
            app.MaxtimebetweenfixationsLabel = uilabel(app.MergePanel);
            app.MaxtimebetweenfixationsLabel.Text = 'Max time between fixations:';
            app.tbMaxMergeTime = uieditfield(app.MergePanel, 'numeric');
            app.tbMaxMergeTime.ValueChangedFcn = createCallbackFcn(app, @tbMaxMergeTimeValueChanged, true);
            app.tbMaxMergeTime.Value = 75;
            app.msLabel_3 = uilabel(app.MergePanel);
            app.msLabel_3.Text = 'ms';
            app.MaxanglebetweenfixationsLabel = uilabel(app.MergePanel);
            app.MaxanglebetweenfixationsLabel.Text = 'Max angle between fixations:';
            app.tbMaxMergeAngle = uieditfield(app.MergePanel, 'numeric');
            app.tbMaxMergeAngle.ValueChangedFcn = createCallbackFcn(app, @tbMaxMergeAngleValueChanged, true);
            app.tbMaxMergeAngle.Value = 0.5;
            app.degLabel = uilabel(app.MergePanel);
            app.degLabel.Text = 'deg';
            app.ddMergeOrder = uidropdown(app.MergePanel);
            app.ddMergeOrder.Items = {'1', '2'};
            app.ddMergeOrder.ValueChangedFcn = createCallbackFcn(app, @ddMergeOrderValueChanged, true);
            app.ddMergeOrder.Tooltip = {'Order of merge/discard fixations:'; '1 - merge adjacent fixations then discard short fixations'; '2 - discard short fixations then merge adjacent fixations'};
            app.ddMergeOrder.Value = '1';
            app.DiscardPanel = uipanel(app.ParseDataTab);
            app.DiscardPanel.Title = 'Discard short fixations';
            app.cbxDiscard = uicheckbox(app.DiscardPanel);
            app.cbxDiscard.ValueChangedFcn = createCallbackFcn(app, @cbxDiscardValueChanged, true);
            app.cbxDiscard.Text = 'Enabled';
            app.cbxDiscard.Value = true;
            app.MinimumfixationdurationLabel = uilabel(app.DiscardPanel);
            app.MinimumfixationdurationLabel.Text = 'Minimum fixation duration:';
            app.tbMinFixationTime = uieditfield(app.DiscardPanel, 'numeric');
            app.tbMinFixationTime.ValueChangedFcn = createCallbackFcn(app, @tbMinFixationTimeValueChanged, true);
            app.tbMinFixationTime.Value = 60;
            app.msLabel_4 = uilabel(app.DiscardPanel);
            app.msLabel_4.Text = 'ms';
            app.ddDiscardOrder = uidropdown(app.DiscardPanel);
            app.ddDiscardOrder.Items = {'1', '2'};
            app.ddDiscardOrder.ValueChangedFcn = createCallbackFcn(app, @ddDiscardOrderValueChanged, true);
            app.ddDiscardOrder.Tooltip = {'Order of merge/discard fixations:'; '1 - discard short fixations then merge adjacent fixations'; '2 - merge adjacent fixations then discard short fixations'};
            app.ddDiscardOrder.Value = '2';
            app.ParseDataintoFixationsLabel = uilabel(app.ParseDataTab);
            app.ParseDataintoFixationsLabel.Text = 'Parse Data into Fixations';
            app.ddParseYesNo = uidropdown(app.ParseDataTab);
            app.ddParseYesNo.Items = {'Off', 'On'};
            app.ddParseYesNo.ValueChangedFcn = createCallbackFcn(app, @ddParseYesNoValueChanged, true);
            app.ddParseYesNo.Value = 'Off';
            app.btnNext1 = uibutton(app.ParseDataTab, 'push');
            app.btnNext1.ButtonPushedFcn = createCallbackFcn(app, @btnNext1Pushed, true);
            app.btnNext1.Text = 'Next';
            app.btnResetDefault = uibutton(app.ParseDataTab, 'push');
            app.btnResetDefault.ButtonPushedFcn = createCallbackFcn(app, @btnResetDefaultButtonPushed, true);
            app.btnResetDefault.Text = 'Reset to Default';
            app.SelectTaskTab = uitab(app.TabGroup);
            app.SelectTaskTab.Title = 'Select Task';
            app.ddChooseTask = uidropdown(app.SelectTaskTab);
            app.ddChooseTask.Items = {' '};
            app.ddChooseTask.ValueChangedFcn = createCallbackFcn(app, @ddChooseTaskValueChanged, true);
            app.ddChooseTask.Value = ' ';
            app.lblChooseTask = uilabel(app.SelectTaskTab);
            app.lblChooseTask.Text = 'Choose Task';
            app.lblSelectImages = uilabel(app.SelectTaskTab);
            app.lblSelectImages.Text = 'Select Images for processing';
            app.lbxImages = uilistbox(app.SelectTaskTab);
            app.lbxImages.Items = {' '};
            app.lbxImages.Multiselect = 'on';
            app.lbxImages.ValueChangedFcn = createCallbackFcn(app, @lbxImagesValueChanged, true);
            app.lbxImages.Value = {};
            app.cbxSelectAllImages = uicheckbox(app.SelectTaskTab);
            app.cbxSelectAllImages.ValueChangedFcn = createCallbackFcn(app, @cbxSelectAllImagesValueChanged, true);
            app.cbxSelectAllImages.Text = 'Select All';
            app.btnAddImage = uibutton(app.SelectTaskTab, 'push');
            app.btnAddImage.ButtonPushedFcn = createCallbackFcn(app, @btnAddImageButtonPushed, true);
            app.btnAddImage.Text = 'Add >>';
            app.lbxSelectedImages = uilistbox(app.SelectTaskTab);
            app.lbxSelectedImages.Items = {' '};
            app.lbxSelectedImages.Multiselect = 'on';
            app.lbxSelectedImages.Value = {};
            app.lblSelectedImages = uilabel(app.SelectTaskTab);
            app.lblSelectedImages.Text = 'Selected Images';
            app.lbxFinalImagesAois = uilistbox(app.SelectTaskTab);
            app.lbxFinalImagesAois.Items = {' '};
            app.lbxFinalImagesAois.Multiselect = 'on';
            app.lbxFinalImagesAois.ValueChangedFcn = createCallbackFcn(app, @lbxFinalImagesAoisValueChanged, true);
            app.lbxFinalImagesAois.Value = {};
            app.btnRemoveImage = uibutton(app.SelectTaskTab, 'push');
            app.btnRemoveImage.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveImageButtonPushed, true);
            app.btnRemoveImage.Text = '<< Remove';
            app.lblFinalImagesAois = uilabel(app.SelectTaskTab);
            app.lblFinalImagesAois.Text = 'AOI List';
            app.lblFinalImagesAois_2 = uilabel(app.SelectTaskTab);
            app.lblFinalImagesAois_2.Text = 'Select AOIs for processing';
            app.cbxSelectAllAois = uicheckbox(app.SelectTaskTab);
            app.cbxSelectAllAois.ValueChangedFcn = createCallbackFcn(app, @cbxSelectAllAoisValueChanged, true);
            app.cbxSelectAllAois.Text = 'Select All';
            app.lbxAois = uilistbox(app.SelectTaskTab);
            app.lbxAois.Items = {' '};
            app.lbxAois.Multiselect = 'on';
            app.lbxAois.Value = {};
            app.btnAddAois = uibutton(app.SelectTaskTab, 'push');
            app.btnAddAois.ButtonPushedFcn = createCallbackFcn(app, @btnAddAoisButtonPushed, true);
            app.btnAddAois.Text = 'Add >>';
            app.btnRemoveAois = uibutton(app.SelectTaskTab, 'push');
            app.btnRemoveAois.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveAoisButtonPushed, true);
            app.btnRemoveAois.Text = '<< Remove';
            app.btnBack2 = uibutton(app.SelectTaskTab, 'push');
            app.btnBack2.ButtonPushedFcn = createCallbackFcn(app, @btnBack2ButtonPushed, true);
            app.btnBack2.Text = 'Back';
            app.btnNext2 = uibutton(app.SelectTaskTab, 'push');
            app.btnNext2.ButtonPushedFcn = createCallbackFcn(app, @btnNext2ButtonPushed, true);
            app.btnNext2.Text = 'Next';
            app.cbxSelectAllAddedImages = uicheckbox(app.SelectTaskTab);
            app.cbxSelectAllAddedImages.ValueChangedFcn = createCallbackFcn(app, @cbxSelectAllAddedImagesValueChanged, true);
            app.cbxSelectAllAddedImages.Text = 'Select All';
            app.cbxSelectAllAddedAois = uicheckbox(app.SelectTaskTab);
            app.cbxSelectAllAddedAois.ValueChangedFcn = createCallbackFcn(app, @cbxSelectAllAddedAoisValueChanged, true);
            app.cbxSelectAllAddedAois.Text = 'Select All';
            app.TemporalFilterTab = uitab(app.TabGroup);
            app.TemporalFilterTab.Title = 'Temporal Filter';
            app.btnBack3 = uibutton(app.TemporalFilterTab, 'push');
            app.btnBack3.ButtonPushedFcn = createCallbackFcn(app, @btnBack3ButtonPushed, true);
            app.btnBack3.Text = 'Back';
            app.btnNext3 = uibutton(app.TemporalFilterTab, 'push');
            app.btnNext3.ButtonPushedFcn = createCallbackFcn(app, @btnNext3ButtonPushed, true);
            app.btnNext3.Text = 'Next';
            app.ddSelectAnchor = uidropdown(app.TemporalFilterTab);
            app.ddSelectAnchor.Items = {'Stimulus onset time', 'Gap Overlap PS onset', ''};
            app.ddSelectAnchor.ValueChangedFcn = createCallbackFcn(app, @ddSelectAnchorValueChanged, true);
            app.ddSelectAnchor.Value = 'Stimulus onset time';
            app.lblSelectAnchor = uilabel(app.TemporalFilterTab);
            app.lblSelectAnchor.Text = 'Select Anchor Point';
            app.WindowStartPanel = uipanel(app.TemporalFilterTab);
            app.WindowStartPanel.Title = 'Select Window Start Time';
            app.sldWindowStart = uislider(app.WindowStartPanel);
            app.sldWindowStart.Limits = [-10000 10000];
            app.sldWindowStart.MajorTicks = [-10000 -7500 -5000 -2500 0 2500 5000 7500 10000];
            app.sldWindowStart.MajorTickLabels = {'-10000', '-7500', '-5000', '-2500', '0', '2500', '5000', '7500', '10000'};
            app.sldWindowStart.ValueChangedFcn = createCallbackFcn(app, @sldWindowStartValueChanged, true);
            app.sldWindowStart.ValueChangingFcn = createCallbackFcn(app, @sldWindowStartValueChanging, true);
            app.txtWindowStart = uieditfield(app.WindowStartPanel, 'numeric');
            app.txtWindowStart.Limits = [-10000 10000];
            app.txtWindowStart.ValueDisplayFormat = '%.0f';
            app.txtWindowStart.ValueChangedFcn = createCallbackFcn(app, @txtWindowStartValueChanged, true);
            app.lblSelectWindowStart = uilabel(app.WindowStartPanel);
            app.lblSelectWindowStart.Text = 'ms';
            app.WindowEndPanel = uipanel(app.TemporalFilterTab);
            app.WindowEndPanel.Title = 'Select Window End Time';
            app.lblSelectWindowEnd = uilabel(app.WindowEndPanel);
            app.lblSelectWindowEnd.Text = 'ms';
            app.sldWindowEnd = uislider(app.WindowEndPanel);
            app.sldWindowEnd.Limits = [-10000 10000];
            app.sldWindowEnd.MajorTicks = [-10000 -7500 -5000 -2500 0 2500 5000 7500 10000];
            app.sldWindowEnd.MajorTickLabels = {'-10000', '-7500', '-5000', '-2500', '0', '2500', '5000', '7500', '10000'};
            app.sldWindowEnd.ValueChangedFcn = createCallbackFcn(app, @sldWindowEndValueChanged, true);
            app.sldWindowEnd.ValueChangingFcn = createCallbackFcn(app, @sldWindowEndValueChanging, true);
            app.txtWindowEnd = uieditfield(app.WindowEndPanel, 'numeric');
            app.txtWindowEnd.ValueChangedFcn = createCallbackFcn(app, @txtWindowEndValueChanged, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.LocateStudyFolderLabel = uilabel(app.DataTab);
            app.LocateStudyFolderLabel.Text = 'Locate Study Folder';
            app.txtSubjectPath = uieditfield(app.DataTab, 'text');
            app.btnGetSubjectPath = uibutton(app.DataTab, 'push');
            app.btnGetSubjectPath.ButtonPushedFcn = createCallbackFcn(app, @btnGetSubjectPathPushed, true);
            app.btnGetSubjectPath.Text = '...';
            app.btnBack4 = uibutton(app.DataTab, 'push');
            app.btnBack4.ButtonPushedFcn = createCallbackFcn(app, @btnBack4ButtonPushed, true);
            app.btnBack4.Text = 'Back';
            app.btnNext4 = uibutton(app.DataTab, 'push');
            app.btnNext4.ButtonPushedFcn = createCallbackFcn(app, @btnNext4ButtonPushed, true);
            app.btnNext4.Text = 'Next';
            app.cbxTotalFixationDuration = uicheckbox(app.DataTab);
            app.cbxTotalFixationDuration.Enable = 'off';
            app.cbxTotalFixationDuration.Text = 'Output Total Fixation Duration';
            app.cbxTimeToFirstFixate = uicheckbox(app.DataTab);
            app.cbxTimeToFirstFixate.Enable = 'off';
            app.cbxTimeToFirstFixate.Text = 'Output Time to First Fixate';
            app.tblSubjects = uitable(app.DataTab);
            app.tblSubjects.ColumnName = {'Include?'; 'Subject No.'};
            app.tblSubjects.RowName = {};
            app.tblSubjects.ColumnEditable = [true false];
            app.tblSubjects.Tooltip = {'Subfolders within the study path are searched and subjects will be returned if a gaze folder is found'};
            app.SelectsubjectstoincludeLabel = uilabel(app.DataTab);
            app.SelectsubjectstoincludeLabel.Text = 'Select subjects to include';
            app.cbxAllSubjects = uicheckbox(app.DataTab);
            app.cbxAllSubjects.ValueChangedFcn = createCallbackFcn(app, @cbxAllSubjectsValueChanged, true);
            app.cbxAllSubjects.Text = 'Select/Deselect All';
            app.cbxAllSubjects.Value = true;
            app.cbxTotalLookingTime = uicheckbox(app.DataTab);
            app.cbxTotalLookingTime.Text = 'Output Total Looking Time';
            app.cbxTotalLookingTime.Value = true;
            app.cbxRawFilter = uicheckbox(app.DataTab);
            app.cbxRawFilter.Enable = 'off';
            app.cbxRawFilter.Text = 'Output Raw+Filter';
            app.cbxTotalFixationCount = uicheckbox(app.DataTab);
            app.cbxTotalFixationCount.Enable = 'off';
            app.cbxTotalFixationCount.Text = 'Output Total Fixation Count';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.tblReview = uitable(app.ReviewTab);
            app.tblReview.ColumnName = {'GROUP'; 'PARAMETER/IMAGE'; 'SETTING/AOIs'};
            app.tblReview.RowName = {};
            app.btnBack5 = uibutton(app.ReviewTab, 'push');
            app.btnBack5.ButtonPushedFcn = createCallbackFcn(app, @btnBack5ButtonPushed, true);
            app.btnBack5.Text = 'Back';
            app.btnRun = uibutton(app.ReviewTab, 'push');
            app.btnRun.ButtonPushedFcn = createCallbackFcn(app, @btnRunButtonPushed, true);
            app.btnRun.Text = 'Run';
            app.TextArea = uitextarea(app.ReviewTab);
            % Show the figure after all components are created
            app.EyeTrackRunTaskUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EyeTrackRunTask

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EyeTrackRunTaskUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EyeTrackRunTaskUIFigure)
        end
    end
end